classdef PlotAntenna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlottingOptionsPanel    matlab.ui.container.Panel
        SavePlotsButton         matlab.ui.control.Button
        FrequencyDropDown       matlab.ui.control.DropDown
        FrequencyDropDownLabel  matlab.ui.control.Label
        AngleDropDown           matlab.ui.control.DropDown
        AngleDropDownLabel      matlab.ui.control.Label
        PlotButton              matlab.ui.control.Button
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RFapp
    end
    
    methods (Access = private)

        function [Frequency, sParameters] = read_s2p(~, Filename)
            % This function reads an s2p file and collects the frequency
            % and s-parameter data.
            data = read(rfdata.data, Filename);
            Frequency = data.Freq;
            sParameters = extract(data, 'S_PARAMETERS');
        end

        function closestValue = findClosestValue(~, array, value)
            [~, idx] = min(abs(array - value));
            closestValue = array(idx);
        end

        function plotRadiationPattern(app, Angles, AntennaGain, Frequency)
            figure('Name', 'Radiation Pattern');
            polarplot(deg2rad(Angles), AntennaGain, 'b-');
            title(sprintf('Radiation Pattern at %.2f GHz', Frequency/1e9));
            rlim([min(AntennaGain)-1 max(AntennaGain)+1]);
            thetalim([0 360]);
        end
        
        function plotGainVsAngle(app, Angles, AntennaGain, Frequency)
            figure('Name', 'Gain vs Angle');
            plot(Angles, AntennaGain, 'b-');
            xlabel('Angle (degrees)');
            ylabel('Gain (dBi)');
            title(sprintf('Antenna Gain vs Angle at %.2f GHz', Frequency/1e9));
            xlim([0 360]);
            grid on;
        end

        function plotGainVsFrequency(app, Frequency, AntennaGain, Angle, s2p_Frequency, s2p_Gain)
            figure('Name', 'Gain vs Frequency');
            plot(Frequency/1e9, AntennaGain, 'b-', 'DisplayName', 'Measured');
            hold on;
            if ~isempty(s2p_Frequency)
                plot(s2p_Frequency/1e9, s2p_Gain, 'r--', 'DisplayName', 'Simulated (S2P)');
                legend('show');
            end
            xlabel('Frequency (GHz)');
            ylabel('Gain (dBi)');
            title(sprintf('Antenna Gain vs Frequency at %d%s', Angle, '$^\circ$'));
            grid on;
            hold off;
        end

        function plotReturnLoss(app, Frequency, S11, s2p_Frequency, s2p_S11)
            figure('Name', 'Return Loss (S11)');
            plot(Frequency/1e9, S11, 'b-', 'DisplayName', 'Measured');
            hold on;
            if ~isempty(s2p_Frequency)
                plot(s2p_Frequency/1e9, s2p_S11, 'r--', 'DisplayName', 'Simulated (S2P)');
                legend('show');
            end
            xlabel('Frequency (GHz)');
            ylabel('Return Loss (dB)');
            title('Return Loss vs Frequency');
            ylim([-50 0])
            grid on;
            hold off;
        end

        function PlottingScript(app, fileName, varargin)
            % This function has three configurations.
            % function('FileName', 'Angle', angle): Plots gain vs frequency for the specified angle using only measured data.
            % function('FileName', 'Frequency', frequency): Plots radiation pattern and gain vs angle for the specified frequency using only measured data.
            % function('FileName', 'Angle', angle, 'S2PFile', 'S2PFile.s2p'): Plots gain vs frequency for the specified angle, including both measured and simulated (S2P) data.
            
            p = inputParser;
            addOptional(p, 'Angle', [], @(x) isnumeric(x) && isscalar(x));
            addOptional(p, 'Frequency', [], @(x) isnumeric(x) && isscalar(x));
            addOptional(p, 'S2PFile', '', @(x) ischar(x) || isstring(x));
            parse(p, varargin{:});
            
            specificAngle = p.Results.Angle;
            specificFrequency = p.Results.Frequency;
            s2pFile = p.Results.S2PFile;
        
            % Load data from Excel file
            dataTable = readtable([fileName '.xlsx']);
            
            % Extract data from table
            table_angle_data = dataTable.Table_Angle;
            frequency_data = dataTable.Frequency_Hz;
            gain_data = dataTable.Gain_dBi;
            S11_data = dataTable.S11_dB;
        
            % Load S2P file if provided
            s2p_freq = [];
            s2p_S11_dB = [];
            s2p_S21_dB = [];
            if ~isempty(s2pFile)
                [s2p_freq, s2p_S] = read_s2p(s2pFile);
                s2p_S11_dB = 20*log10(abs(s2p_S(:,1,1))); 
                s2p_S21_dB = 20*log10(abs(s2p_S(:,2,1)));  
            end
        
            % Filter data based on input
            if ~isempty(specificAngle) && isempty(specificFrequency)
                % Only angle specified
                closestAngle = findClosestValue(table_angle_data, specificAngle);
                angleIndices = table_angle_data == closestAngle;
                
                filtered_freqs = frequency_data(angleIndices);
                filtered_gains = gain_data(angleIndices);
                filtered_S11 = S11_data(angleIndices);
                
                [sorted_freqs, sortIdx] = sort(filtered_freqs);
                sorted_gains = filtered_gains(sortIdx);
                sorted_S11 = filtered_S11(sortIdx);
                
                plotGainVsFrequency(sorted_freqs, sorted_gains, closestAngle, s2p_freq, s2p_S21_dB);
                plotReturnLoss(sorted_freqs, sorted_S11, s2p_freq, s2p_S11_dB);
            elseif isempty(specificAngle) && ~isempty(specificFrequency)
                % Only frequency specified
                closestFreq = findClosestValue(frequency_data, specificFrequency);
                freqIndices = frequency_data == closestFreq;
                
                filtered_angles = table_angle_data(freqIndices);
                filtered_gains = gain_data(freqIndices);
                filtered_S11 = S11_data(freqIndices);
                
                [sorted_angles, sortIdx] = sort(filtered_angles);
                sorted_gains = filtered_gains(sortIdx);
                sorted_S11 = filtered_S11(sortIdx);
                
                plotRadiationPattern(sorted_angles, sorted_gains, closestFreq);
                plotGainVsAngle(sorted_angles, sorted_gains, closestFreq);
                %plotReturnLossAngles(sorted_angles, sorted_S11, closestFreq);
            else
                error('Please specify either an angle or a frequency, but not both.');
            end
        end

        function initializeDropdownMenus(app)
            % This function initalizes the dropdown menus with the
            % avalaible frequencies. Users can choose which functions to
            % plot via the dropdown menu.
            angles = unique(app.RFapp.input_Angles);
            frequencies = unique(app.RFapp.input_Freqs);

            
            %formattedFreqs = arrayfun(@(f) app.formatFreq(f), freqs, 'UniformOutput', false);
            app.AngleDropDown.Items = angles;
            app.FrequencyDropDown.Items = frequencies;
        end
        
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.RFapp = mainApp;
            app.initializeDropdownMenus();

            
            app.UIFigure.WindowState = 'maximized';
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");
            switch choice
                case "OK"
                    if isvalid(app.RFapp)
                        app.RFapp.PlotAntennaData.Enable = 'on';
                    end
                    delete(app)
                case "Cancel"
                    return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotButton = uibutton(app.PlottingOptionsPanel, 'push');
            app.PlotButton.Text = 'Plot';
            app.AngleDropDownLabel = uilabel(app.PlottingOptionsPanel);
            app.AngleDropDownLabel.Text = 'Angle';
            app.AngleDropDown = uidropdown(app.PlottingOptionsPanel);
            app.FrequencyDropDownLabel = uilabel(app.PlottingOptionsPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.PlottingOptionsPanel);
            app.SavePlotsButton = uibutton(app.PlottingOptionsPanel, 'push');
            app.SavePlotsButton.Text = 'Save Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotAntenna(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end