classdef PlotSingle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        SavePlotsButton  matlab.ui.control.Button
        SingleTable      matlab.ui.control.Table
        SingleGain       matlab.ui.control.UIAxes
        SingleInOut      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RFapp
    end
    
    methods (Access = private)
        
        function plotSingleMeasurement(app, RFapp)
            % This function plots the data from the single measurement
            % type. The first plot shows input power vs. output power. The
            % second plot shows the DE, PAE, gain, and compression points
            % if found.
            cla(app.SingleInOut);
            cla(app.SingleGain);

            % Window size for the moving average
            windowSize = 3; 
            smoothedGain = movmean(RFapp.PAGain, windowSize);
            maxGain = max(smoothedGain);
            idx_1dB = find(smoothedGain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(smoothedGain <= (maxGain - 3), 1, 'first'); 
            [saturation_power, saturation_idx] = max(RFapp.output_PdBm);

            % Plot Input Power vs Output Power
            plot(app.SingleInOut, RFapp.input_PdBm_Single, RFapp.output_PdBm, 'blue');
            xlabel(app.SingleInOut, 'Input Power (dBm)');
            ylabel(app.SingleInOut, 'Output Power (dBm)');
            title(app.SingleInOut, 'Output Power vs. Input Power');

            % Plot Gain on the left y-axis
            yyaxis(app.SingleGain, 'left');
            h1 = plot(app.SingleGain, RFapp.output_PdBm, RFapp.PAGain, 'black');
            hold(app.SingleGain, 'on');

            % Plot the compression points
            if ~isempty(idx_1dB)
                h4 = plot(app.SingleGain, RFapp.output_PdBm(idx_1dB), RFapp.PAGain(idx_1dB), 'blueo', 'MarkerSize', 10);
            end
            if ~isempty(idx_3dB)
                h5 = plot(app.SingleGain, RFapp.output_PdBm(idx_3dB), RFapp.PAGain(idx_3dB), 'redo', 'MarkerSize', 10);
            end
            if ~isempty(saturation_idx)
                h6 = plot(app.SingleGain, saturation_power, RFapp.PAGain(saturation_idx), 'greeno', 'MarkerSize', 10);
            end
            ylabel(app.SingleGain, 'Gain (dB)');

            % Plot DE and PAE on the right y-axis
            yyaxis(app.SingleGain, 'right');
            h2 = plot(app.SingleGain, RFapp.output_PdBm, RFapp.DE, 'blue-');
            h3 = plot(app.SingleGain, RFapp.output_PdBm, RFapp.PAE, 'red--');
            ylabel(app.SingleGain, 'Efficiency (%)');

            hold(app.SingleGain, 'off');
            xlabel(app.SingleGain, 'Output Power (dBm)');
            title(app.SingleGain, 'Gain, DE, and PAE vs. Output Power');

            % Legend order
            legendEntries = {'Gain', 'DE', 'PAE'};
            legendHandles = [h1, h2, h3];
            if exist('h4', 'var')
                legendHandles(end+1) = h4; 
                legendEntries{end+1} = 'Gain_{-1dB}'; 
            end
            if exist('h5', 'var')
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'Gain_{-3dB}'; 
            end
            if exist('h6', 'var')
                legendHandles(end+1) = h6; 
                legendEntries{end+1} = 'Saturation'; 
            end
            legend(app.SingleGain, legendHandles, legendEntries, 'Location', 'west');
            
            % Populate the table with frequency, compression points, and saturation power.
            T = table(RFapp.input_Freq_Single(1)/1E9, RFapp.output_PdBm(idx_1dB), RFapp.output_PdBm(idx_3dB), saturation_power);
            app.SingleTable.Data = T;   

            % Call helper functions to enhance the plots
            improveTableAppearance(app.SingleTable);
            improveAxesAppearance(app.SingleInOut);
            improveAxesAppearance(app.SingleGain);   
        end

        function saveAxesFigure(~, axesObj)
            % Save the figure to the 'Pics' folder in the current workspace
            % axesObj: handle to the UIAxes object
            
            % Create 'Pics' folder if it doesn't exist
            if ~exist('Pics', 'dir')
                mkdir('Pics');
            end
            
            % Generate a unique filename based on current date and time
            currentDateTime = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            fileName = sprintf('figure_%s.png', char(currentDateTime));
            filePath = fullfile('Pics', fileName);
            
            % Save the figure using exportgraphics
            exportgraphics(axesObj, filePath, 'Resolution', 300);
            fprintf('Figure saved to: %s\n', filePath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.RFapp = mainApp;
            plotSingleMeasurement(app, app.RFapp);
            app.UIFigure.WindowState = 'maximized';
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");
    
            switch choice
                case "OK"
                    if isvalid(app.RFapp)
                        app.RFapp.PlotPAData.Enable = 'on';
                    end
                    delete(app)
                case "Cancel"
                    return
            end
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            app.saveAxesFigure(app.SingleInOut)
            app.saveAxesFigure(app.SingleGain)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SingleInOut = uiaxes(app.GridLayout);
            title(app.SingleInOut, 'Title')
            xlabel(app.SingleInOut, 'X')
            ylabel(app.SingleInOut, 'Y')
            zlabel(app.SingleInOut, 'Z')
            app.SingleGain = uiaxes(app.GridLayout);
            title(app.SingleGain, 'Title')
            xlabel(app.SingleGain, 'X')
            ylabel(app.SingleGain, 'Y')
            zlabel(app.SingleGain, 'Z')
            app.SingleTable = uitable(app.GridLayout);
            app.SingleTable.ColumnName = {'Frequency (GHz)'; 'P1dB (dBm)'; 'P3dB (dBm)'; 'PSat (dBm)'};
            app.SingleTable.RowName = {};
            app.SavePlotsButton = uibutton(app.GridLayout, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotSingle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end