classdef PlotSweep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        PlottingOptionsPanel         matlab.ui.container.Panel
        SavePlotsButton              matlab.ui.control.Button
        EndFrequencyDropDown         matlab.ui.control.DropDown
        EndFrequencyDropDownLabel    matlab.ui.control.Label
        StartFrequencyDropDown       matlab.ui.control.DropDown
        StartFrequencyDropDownLabel  matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        SweepTable                   matlab.ui.control.Table
        SweepPeakGain                matlab.ui.control.UIAxes
        SweepCompressionPoints       matlab.ui.control.UIAxes
        SweepPeakDEPAE               matlab.ui.control.UIAxes
        SweepGain                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RFapp
        firstIdx 
        secondIdx
    end
    
    methods (Access = private)

        function peakValues = measureRFpeaks(~, RFapp)
            % This function is used with the sweep type measurement, it
            % calculates the peak values of several RF paramters (Gain, DE,
            % PAE, Saturation Power, -1dB and -3dB compression points).

            peak_Gain = zeros(size(RFapp.input_Freq_Sweep, 1), 1);
            peak_1dB = zeros(size(RFapp.input_Freq_Sweep, 1), 1);
            peak_3dB = zeros(size(RFapp.input_Freq_Sweep, 1), 1);
        
            % Window size for the moving average
            windowSize = 3; 
            
            for i = 1:size(RFapp.input_Freq_Sweep, 1)
                smoothedGain = movmean(RFapp.PAGain(i, :), windowSize);
                peak_Gain(i) = max(smoothedGain);
            
                % Find the -1dB compression point
                idx_1dB = find(smoothedGain <= (peak_Gain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak_1dB(i) = RFapp.output_PdBm(i, idx_1dB);
                else
                    peak_1dB(i) = NaN;
                end
            
                % Find the -3dB compression point
                idx_3dB = find(smoothedGain <= (peak_Gain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak_3dB(i) = RFapp.output_PdBm(i, idx_3dB);
                else
                    peak_3dB(i) = NaN;
                end
            end
        
            saturation_PdBm = max(RFapp.output_PdBm, [], 2);
            peak_DE = max(RFapp.DE, [], 2);
            peak_PAE = max(RFapp.PAE, [], 2);
        
            peakValues = {saturation_PdBm, peak_Gain, peak_DE, peak_PAE, peak_1dB', peak_3dB'};
        end
        
        function plotSweepMeasurement(app, RFapp)
            % This function plots the data from the sweep measurement type.
            % The first plots shows gain vs. output power. Peak DE, PAE,
            % and gain are also plotted for each frequency. Finally the
            % saturation, and compression points are plotted. If no curve
            % appears for the compression points then there was a frequency
            % with no compression.
            cla(app.SweepGain);
            cla(app.SweepCompressionPoints);
            cla(app.SweepPeakGain);
            cla(app.SweepPeakDEPAE);
            app.SweepTable.Data = [];

            % Get peak values
            peakValues = app.measureRFpeaks(RFapp);          
            
            % Plot Gain versus output power over the frequency sweep
            hold(app.SweepGain, 'on'); 
            for i = app.firstIdx:app.secondIdx
                %plot(app.SweepGain, app.output_PdBm(i, :), app.Gain(i, :), 'DisplayName', app.formatFreq(app.input_Freq_Sweep(i)));
                plot(app.SweepGain, RFapp.output_PdBm(i, :), RFapp.PAGain(i, :));
            end
            hold(app.SweepGain, 'off');
            title(app.SweepGain, 'Gain vs. Output Power');
            xlabel(app.SweepGain, 'Output Power (dBm)');
            ylabel(app.SweepGain, 'Gain (dB)');
            %legend(app.SweepGain, 'show', 'Location', 'best');
            
            % % Plot peak Gain over the frequency sweep
            plot(app.SweepCompressionPoints, RFapp.input_Freq_Sweep(app.firstIdx:app.secondIdx)/1E9, peakValues{2}(app.firstIdx:app.secondIdx), 'blue');
            title(app.SweepCompressionPoints, 'Peak Gain');
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)');
            ylabel(app.SweepCompressionPoints, 'Gain (dB)');
            
            % Plot peak DE and peak PAE over the frequency sweep
            hold(app.SweepPeakGain, 'on');
            h1 = plot(app.SweepPeakGain, RFapp.input_Freq_Sweep(app.firstIdx:app.secondIdx)/1E9, peakValues{3}(app.firstIdx:app.secondIdx), 'blue');
            h2 = plot(app.SweepPeakGain, RFapp.input_Freq_Sweep(app.firstIdx:app.secondIdx)/1E9, peakValues{4}(app.firstIdx:app.secondIdx), 'red');
            hold(app.SweepPeakGain, 'off');
            title(app.SweepPeakGain, 'Peak DE and PAE');
            xlabel(app.SweepPeakGain, 'Frequency (GHz)');
            ylabel(app.SweepPeakGain, 'Efficiency (%)');
            legend(app.SweepPeakGain, [h1, h2], 'DE', 'PAE', 'Location', 'best');

            % Plot saturation power and compression points over the
            % frequency sweep, if a compression point was not found, 
            % nothing is plotted.
            plotHandles = {};
            plotColors = {'black', 'red', 'blue'};
            xData = {[], [], []};
            yData = {[], [], []};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            hold(app.SweepPeakDEPAE, 'on'); 

            %Loop through data and collect non-zero points
            for i = app.firstIdx:app.secondIdx
                if peakValues{1}(i) ~= 0
                    xData{1} = [xData{1}, RFapp.input_Freq_Sweep(i)/1E9];
                    yData{1} = [yData{1}, peakValues{1}(i)];
                end
                if peakValues{5}(i) ~= 0
                    xData{2} = [xData{2}, RFapp.input_Freq_Sweep(i)/1E9];
                    yData{2} = [yData{2}, peakValues{5}(i)];
                end
                if peakValues{6}(i) ~= 0
                    xData{3} = [xData{3}, RFapp.input_Freq_Sweep(i)/1E9];
                    yData{3} = [yData{3}, peakValues{6}(i)];
                end   
            end

            for idx = 1:3
                if ~isempty(xData{idx})
                    plotHandles{end+1} = plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, [plotColors{idx}, '-']);
                    plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, plotColors{idx});
                end
            end

            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)');
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)');
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points');

            if ~isempty(plotHandles)
                legend(app.SweepPeakDEPAE, [plotHandles{:}], legendLabels(1:length(plotHandles)), 'Location', 'best');
            end
            hold(app.SweepPeakDEPAE, 'off');

            % Populate the table with frequency, compression points, and saturation power.
            freqs = RFapp.input_Freq_Sweep(app.firstIdx:app.secondIdx)';
            freqs = freqs(~any(isnan(freqs), 2), :);
            freqs = freqs / 1E9;
            T = table(freqs, peakValues{5}(app.firstIdx:app.secondIdx)', peakValues{6}(app.firstIdx:app.secondIdx)', peakValues{1}(app.firstIdx:app.secondIdx));
            app.SweepTable.Data = T;   

            % Call helper functions to enhance the plots
            improveTableAppearance(app.SweepTable);
            improveAxesAppearance(app.SweepGain);
            improveAxesAppearance(app.SweepCompressionPoints);
            improveAxesAppearance(app.SweepPeakGain);
            improveAxesAppearance(app.SweepPeakDEPAE);
        end
        
        function label = formatFreq(~, Frequency)
            % This function takes in a frequency value and returns it in scientific
            % notation for better visualization.
            if Frequency >= 1e9
                label = sprintf('%.2f GHz', Frequency / 1e9);
            elseif Frequency >= 1e6
                label = sprintf('%.2f MHz', Frequency / 1e6);
            elseif Frequency >= 1e3
                label = sprintf('%.2f kHz', Frequency / 1e3);
            else
                label = sprintf('%.2f Hz', Frequency);
            end     
        end
        
        function initializeDropdownMenus(app)
            % This function initalizes the dropdown menus with the
            % avalaible frequencies. Users can choose which functions to
            % plot via the dropdown menu.
            freqs = app.RFapp.input_Freq_Sweep(app.firstIdx:app.secondIdx)';
            formattedFreqs = arrayfun(@(f) app.formatFreq(f), freqs, 'UniformOutput', false);
            app.StartFrequencyDropDown.Items = formattedFreqs;
            app.EndFrequencyDropDown.Items = formattedFreqs;
            if ~isempty(formattedFreqs)
                app.StartFrequencyDropDown.Value = formattedFreqs{1};
                app.EndFrequencyDropDown.Value = formattedFreqs{end};
            end
        end

        function saveAxesFigure(~, axesObj)
            % Save the figure to the 'Pics' folder in the current workspace
            % axesObj: handle to the UIAxes object
            
            % Create 'Pics' folder if it doesn't exist
            if ~exist('Pics', 'dir')
                mkdir('Pics');
            end
            
            % Generate a unique filename based on current date and time
            currentDateTime = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            fileName = sprintf('figure_%s.png', char(currentDateTime));
            filePath = fullfile('Figures', fileName);
            
            % Save the figure using exportgraphics
            exportgraphics(axesObj, filePath, 'Resolution', 300);
            fprintf('Figure saved to: %s\n', filePath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.RFapp = mainApp;
            app.firstIdx = 1;
            app.secondIdx = size(app.RFapp.output_PdBm, 1);
            plotSweepMeasurement(app, app.RFapp);
            initializeDropdownMenus(app);
            app.UIFigure.WindowState = 'maximized';
            drawnow;
        end

        % Value changed function: StartFrequencyDropDown
        function StartFrequencyDropDownValueChanged(app, event)
            selectedFreq = app.StartFrequencyDropDown.Value;
            app.firstIdx = find(strcmp(app.StartFrequencyDropDown.Items, selectedFreq));  
            if ~isempty(app.firstIdx) && app.firstIdx <= length(app.StartFrequencyDropDown.Items)
                app.EndFrequencyDropDown.Items = app.StartFrequencyDropDown.Items(app.firstIdx:end);
            else
                app.EndFrequencyDropDown.Items = {};
            end
            if ~isempty(app.EndFrequencyDropDown.Items)
                app.EndFrequencyDropDown.Value = app.EndFrequencyDropDown.Items{1};
            end
            app.secondIdx = app.firstIdx;
        end

        % Value changed function: EndFrequencyDropDown
        function EndFrequencyDropDownValueChanged(app, event)
            selectedFreq = app.EndFrequencyDropDown.Value;
            app.secondIdx = find(strcmp(app.EndFrequencyDropDown.Items, selectedFreq));
            if strcmp(selectedFreq, app.StartFrequencyDropDown.Value)
                app.secondIdx = app.firstIdx;
            else
                app.secondIdx = app.firstIdx + app.secondIdx - 1;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotSweepMeasurement(app, app.RFapp);
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");
            switch choice
                case "OK"
                    if isvalid(app.RFapp)
                        app.RFapp.PlotPAData.Enable = 'on';
                    end
                    delete(app)
                case "Cancel"
                    return
            end
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            app.saveAxesFigure(app.SweepGain);
            app.saveAxesFigure(app.SweepCompressionPoints);
            app.saveAxesFigure(app.SweepPeakGain);
            app.saveAxesFigure(app.SweepPeakDEPAE);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SweepGain = uiaxes(app.GridLayout);
            title(app.SweepGain, 'Title')
            xlabel(app.SweepGain, 'X')
            ylabel(app.SweepGain, 'Y')
            zlabel(app.SweepGain, 'Z')
            app.SweepPeakDEPAE = uiaxes(app.GridLayout);
            title(app.SweepPeakDEPAE, 'Title')
            xlabel(app.SweepPeakDEPAE, 'X')
            ylabel(app.SweepPeakDEPAE, 'Y')
            zlabel(app.SweepPeakDEPAE, 'Z')
            app.SweepCompressionPoints = uiaxes(app.GridLayout);
            title(app.SweepCompressionPoints, 'Title')
            xlabel(app.SweepCompressionPoints, 'X')
            ylabel(app.SweepCompressionPoints, 'Y')
            zlabel(app.SweepCompressionPoints, 'Z')
            app.SweepPeakGain = uiaxes(app.GridLayout);
            title(app.SweepPeakGain, 'Title')
            xlabel(app.SweepPeakGain, 'X')
            ylabel(app.SweepPeakGain, 'Y')
            zlabel(app.SweepPeakGain, 'Z')
            app.SweepTable = uitable(app.GridLayout);
            app.SweepTable.ColumnName = {'Frequency (GHz)'; 'P1dB (dBm)'; 'P3dB (dBm)'; 'PSat (dBm)'};
            app.SweepTable.RowName = {};
            app.PlottingOptionsPanel = uipanel(app.GridLayout);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotButton = uibutton(app.PlottingOptionsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StartFrequencyDropDownLabel = uilabel(app.PlottingOptionsPanel);
            app.StartFrequencyDropDownLabel.Text = 'Start Frequency';
            app.StartFrequencyDropDown = uidropdown(app.PlottingOptionsPanel);
            app.StartFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @StartFrequencyDropDownValueChanged, true);
            app.EndFrequencyDropDownLabel = uilabel(app.PlottingOptionsPanel);
            app.EndFrequencyDropDownLabel.Text = 'End Frequency';
            app.EndFrequencyDropDown = uidropdown(app.PlottingOptionsPanel);
            app.EndFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @EndFrequencyDropDownValueChanged, true);
            app.SavePlotsButton = uibutton(app.PlottingOptionsPanel, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotSweep(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end