classdef RF_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InstrumentControlTab            matlab.ui.container.Tab
        AppExplanationLabel             matlab.ui.control.Label
        PowerSupplySettingsPanel        matlab.ui.container.Panel
        SupplyVoltage                   matlab.ui.control.NumericEditField
        SupplyVoltageVEditFieldLabel    matlab.ui.control.Label
        Label_psu_settings              matlab.ui.control.Label
        Lamp_psu_settings               matlab.ui.control.Lamp
        ApplyVoltageButton              matlab.ui.control.Button
        InstrumentConnectionsPanel      matlab.ui.container.Panel
        DisconnectButton                matlab.ui.control.Button
        SpectrumAnalyzerEditField       matlab.ui.control.EditField
        SpectrumAnalyzerEditFieldLabel  matlab.ui.control.Label
        SignalGeneratorEditField        matlab.ui.control.EditField
        SignalGeneratorEditFieldLabel   matlab.ui.control.Label
        PowerSupplyUnitEditField        matlab.ui.control.EditField
        PowerSupplyUnitEditFieldLabel   matlab.ui.control.Label
        Label_Power_Supply              matlab.ui.control.Label
        Label_Signal_Generator          matlab.ui.control.Label
        Label_Spectrum_Analyzer         matlab.ui.control.Label
        Lamp_Power_Supply               matlab.ui.control.Lamp
        Lamp_Signal_Generator           matlab.ui.control.Lamp
        Lamp_Spectrum_Analyzer          matlab.ui.control.Lamp
        ConnectButton                   matlab.ui.control.Button
        SingleTab                       matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        FilenameSingle                  matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        SavetoFileSingle                matlab.ui.control.Button
        SignalGeneratorSettingsPanel_Single  matlab.ui.container.Panel
        FrequencySingle                 matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        StartPowerSingle                matlab.ui.control.NumericEditField
        StartPowerdBmEditField_2Label   matlab.ui.control.Label
        EndPowerSingle                  matlab.ui.control.NumericEditField
        PowerStepSingle                 matlab.ui.control.NumericEditField
        EndPowerdBmEditField_2Label     matlab.ui.control.Label
        StepSizeEditField_3Label        matlab.ui.control.Label
        SpectrumAnalyzerSettingsPanel_Single  matlab.ui.container.Panel
        CenterFrequencySingle           matlab.ui.control.NumericEditField
        CenterFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SpanSingle                      matlab.ui.control.NumericEditField
        SweepPointsSingle               matlab.ui.control.NumericEditField
        ReferenceLevelSingle            matlab.ui.control.NumericEditField
        StartAcquisitionSingle          matlab.ui.control.Button
        ReferenceLeveldBEditFieldLabel  matlab.ui.control.Label
        SweepPointsEditFieldLabel       matlab.ui.control.Label
        SpanHzEditFieldLabel            matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        FilenameSweep                   matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SavetoFileSweep                 matlab.ui.control.Button
        SignalGeneratorSettingsPanel_Sweep  matlab.ui.container.Panel
        StartFrequencySweep             matlab.ui.control.NumericEditField
        StartFrequencyHzEditFieldLabel  matlab.ui.control.Label
        EndFrequencySweep               matlab.ui.control.NumericEditField
        FrequencyStepSweep              matlab.ui.control.NumericEditField
        StartPowerSweep                 matlab.ui.control.NumericEditField
        EndPowerSweep                   matlab.ui.control.NumericEditField
        PowerStepSweep                  matlab.ui.control.NumericEditField
        StepSizeEditField_2Label        matlab.ui.control.Label
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        StepSizeEditFieldLabel          matlab.ui.control.Label
        EndFrequencyHzEditFieldLabel    matlab.ui.control.Label
        SpectrumAnalyzerSettingsPanel_Sweep  matlab.ui.container.Panel
        SweepPointsSweep                matlab.ui.control.NumericEditField
        ReferenceLevelSweep             matlab.ui.control.NumericEditField
        StartAcquisitionSweep           matlab.ui.control.Button
        ReferenceLeveldBEditFieldLabel_2  matlab.ui.control.Label
        SweepPointsEditField_2Label     matlab.ui.control.Label
    end

    % Properties that correspond to global variables
    properties (Access = private)
        Power_Supply      % Power supply object
        Signal_Generator  % Signal generator object 
        Spectrum_Analyzer % Spectrum analyzer object

        input_PdBm_Single % Input RF power for single measurement (dBm)
        input_PdBm_Sweep  % Input RF power for sweep measurement (dBm)
        input_Freq_Single % Frequency range for single measurement (Hz)
        input_Freq_Sweep  % Frequency range for sweep measurement (Hz)
        
        output_PdBm       % Output RF power (dBm)
        P_DC              % Output DC power (W)
        Gain              % Gain (dB)
        DE                % Dran efficiency (mag)
        PAE               % Power-added efficiency (mag)

    end
    
    methods (Access = private)
   
        function instrument = connectInstrument(~, InstrumentAddress)
            % This function checks if the connection to the instrument is
            % valid, and connects the specified address of the instrument.
            
            try
                instrument = visadev(InstrumentAddress);
                instrument.ByteOrder = 'little-endian';
            catch ME
                instrument = [];
            end
        end

        function displaySettings(app, mode)
            % This function enables/disables certain parts of the app
            % interface.

            switch mode
                case 'startup'
                    app.PowerSupplySettingsPanel.Enable = 'off';
                    app.SignalGeneratorSettingsPanel_Single.Enable = 'off';
                    app.SpectrumAnalyzerSettingsPanel_Single.Enable = 'off';                  
                    app.SignalGeneratorSettingsPanel_Sweep.Enable = 'off';
                    app.SpectrumAnalyzerSettingsPanel_Sweep.Enable = 'off';
                    app.FilenameSingle.Enable = 'off';
                    app.SavetoFileSingle.Visible = 'off';
                    app.FilenameSweep.Enable = 'off';
                    app.SavetoFileSweep.Visible = 'off';
                case 'appliedVoltage'
                    app.SignalGeneratorSettingsPanel_Single.Enable = 'on';
                    app.SpectrumAnalyzerSettingsPanel_Single.Enable = 'on';
                    app.SignalGeneratorSettingsPanel_Sweep.Enable = 'on';
                    app.SpectrumAnalyzerSettingsPanel_Sweep.Enable = 'on';
                    app.FilenameSingle.Enable = 'off';
                    app.SavetoFileSingle.Visible = 'off';
                    app.FilenameSweep.Enable = 'off';
                    app.SavetoFileSweep.Visible = 'off';
                case 'appliedSingle'
                    app.FilenameSingle.Enable = 'on';
                    app.SavetoFileSingle.Visible = 'on';
                case 'appledSweep'
                    app.FilenameSweep.Enable = 'on';
                    app.SavetoFileSweep.Visible = 'on';
            end
        end

        function status = waitForInstrument(~, Instrument)
            % This function waits until the specified instrument is ready
            % to continue operations.

            timeout = 50E-3; % Adjust the timeout duration if needed (s)
            tic;
            while true
                status = query(Instrument, '*OPC?');
                if strcmp(status, '1') || toc > timeout
                    break;
                end
                pause(1); % Adjust the pause duration if needed (s)
            end
        end

        function P = dBm2W(~, PdBm)
            % dBm2mag Converts dBm to W
            % Credit: Alex D. Santiago Vargas, PhD

            P = 10.^((PdBm-30)/10);
        end
      
        function [output_PdBm, P_DC] = measure_RFoutput(app,Signal_Generator,Spectrum_Analyzer,Power_Supply,input_PdBm)
            % This function measures the output RF power (dBm) and the DC 
            % power (W) of the power supply.

            % A 30 dB attenuator was used in the test setup, adjust as
            % needed
            attenuation = 30; 
        
            % Set the amplitude of the signal generator
            writeline(Signal_Generator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %g', input_PdBm));
        
            % Wait until the signal generator is ready
            app.waitForInstrument(Signal_Generator);
        
            % Initiate the measurement process
            writeline(Spectrum_Analyzer, sprintf(':INITiate:CONTinuous %d', 0));
            writeline(Spectrum_Analyzer, ':INITiate:IMMediate');
            
            % Wait until the signal analyzer is ready
            app.waitForInstrument(Spectrum_Analyzer);
        
            % Fetch the trace data
            writeline(Spectrum_Analyzer, sprintf(':TRACe:DATA? %s', 'TRACe1'));
            trace_data = readbinblock(Spectrum_Analyzer, 'double');
        
            % Measure the maximum output power and account for attenuator 
            output_PdBm = max(trace_data) + attenuation;
        
            % Measure the voltage and current at the DC power supply
            V_DC = str2double(writeread(Power_Supply, sprintf(':MEASure:VOLTage:DC? %s', 'P25V'))); 
            I_DC = str2double(writeread(Power_Supply, sprintf(':MEASure:CURRent:DC? %s', 'P25V'))); 
            P_DC = V_DC * I_DC;
        
            % Clear the status register of the spectrum analyzer
            writeline(Spectrum_Analyzer, '*CLS');          
        end

        function [Gain, DE, PAE] = measure_RFparameters(app, input_PdBm, output_PdBm, P_DC)
            % This function uses RF input power (dBm), RF output power 
            % (dBm), and DC power (W) to calculate gain (dB), drain 
            % efficiency (mag), and power added efficiency (mag).
    
            Gain = zeros(size(output_PdBm, 1, 2));
     
            % Calculate the Gain in dB
            for i = 1:size(output_PdBm, 1)
                for j = 1:size(output_PdBm, 2)
                    Gain(i, j) = output_PdBm(i, j) - input_PdBm(j);
                end
            end
    
            % Convert the input and output power from dBm to W
            input_PW = app.dBm2W(input_PdBm);
            output_PW = app.dBm2W(output_PdBm);
     
            % Calculate the DE and PAE
            DE = output_PW ./ P_DC;
            PAE = (output_PW - input_PW) ./ P_DC;
        end

        function peakValues = measure_RFpeaks(app)
            % This function is used with the sweep type measurement, it
            % calculates the peak values of several RF paramters (Gain, DE,
            % PAE, Saturation Power, -1dB and -3dB compression points).

            saturation_PdBm = max(app.output_PdBm, [], 2);
            peak_Gain = max(app.Gain, [], 2);
            peak_DE = max(app.DE, [], 2);
            peak_PAE = max(app.PAE, [], 2);
        
            % Calculate -1dB and -3dB compression points
            peak_1dB = zeros(length(app.input_Freq_Sweep), 1);
            peak_3dB = zeros(length(app.input_Freq_Sweep), 1);
        
            for i = 1:length(app.input_Freq_Sweep)
                % Find the -1dB compression point
                idx_1dB = find(app.Gain(i, :) <= (app.peak_Gain_dB(i) - 1), 1);
                if ~isempty(idx_1dB)
                    peak_1dB(i) = app.output_PdBm(i, idx_1dB);
                else
                    peak_1dB(i) = NaN;
                end
                % Find the -3dB compression point
                idx_3dB = find(app.Gain(i, :) <= (app.peak_Gain_dB(i) - 3), 1);
                if ~isempty(idx_3dB)
                    peak_3dB(i) = app.output_PdBm(i, idx_3dB);
                else
                    peak_3dB(i) = Nan;
                end
            end

            peakValues = {saturation_PdBm, peak_Gain, peak_DE, peak_PAE, peak_1dB, peak_3dB};
        end
        
        function saveToFile(app, measurementType, Filename)
            % This function ...
            input_PdBm = [];
            input_Freq_rep = [];

            % Remove existing extension, if any, and add .xlsx extension
            [~, name, ~] = fileparts(Filename);
            Filename = [name, '.xlsx'];

            % Check which input power array to use
            if strcmp(measurementType, 'single')
                input_PdBm = app.input_PdBm_Single;
                input_Freq_rep = repmat(app.input_Freq_Single, size(input_PdBm, 2), 1);
            elseif strcmp(measurementType, 'sweep')
                input_PdBm = app.input_PdBm_Sweep;
                input_Freq_rep = repmat(app.input_Freq_Sweep, size(input_PdBm, 2), 1);
            end

            % Save data into an Excel file
            data = table(input_Freq_rep, input_PdBm', app.output_PdBm', app.Gain', app.P_DC', app.DE', app.PAE', ...
                'VariableNames', {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency', 'Power Added Efficiency'});
            writetable(data, Filename, 'Sheet', 1);
            
            % If the measurement type is sweep also save the peak values 
            if strcmp(measurementType, 'sweep')
                peakValues = app.measure_RFpeaks;
                % Ensure the arrays are as expected
                disp(['Size of inpput freq', mat2str(size(app.input_Freq_Sweep))]);
                disp(['Size of peak gain: ', mat2str(size(peakValues{2}))]);
                disp(['Size of peak DE: ', mat2str(size(peakValues{3}))]);
                disp(['Size of peak PAE: ', mat2str(size(peakValues{4}))]);
                disp(['Size of peak 1dB: ', mat2str(size(peakValues{5}))]);
                disp(['Size of peak 3dB ', mat2str(size(peakValues{6}))]);
                disp(['Size of peak sat: ', mat2str(size(peakValues{1}))]);

                peak_data = table(app.input_Freq_Sweep', peakValues{1}, peakValues{2}, peakValues{3}, peakValues{4}, peakValues{5}, peakValues{6}, ...
                     'VariableNames', {'Frequency (Hz)','Saturation_Power (dBm)', 'Peak_Gain (dB)', 'Peak_DE', 'Peak_PAE', 'Peak_1dB (dBm)', 'Peak_3dB (dBm)'});
                writetable(peak_data, Filename, 'Sheet', 2);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.displaySettings('startup');
        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)
            Tab = app.MeasurementTypeButtonGroup.SelectedObject;
          
            switch Tab
                case 'Instrument Control'
                    app.TabGroup.SelectedTab = app.InstrumentControlTab;
                case 'Single'
                    app.TabGroup.SelectedTab = app.SingleTab;
                case 'Sweep'
                    app.TabGroup.SelectedTab = app.SweepTab;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % This function connects the instruments via their interface
            % address 
            instrumentNames = {'Power_Supply', 'Signal_Generator', 'Spectrum_Analyzer'};
            instrumentAddresses = {app.PowerSupplyUnitEditField.Value, app.SignalGeneratorEditField.Value, app.SpectrumAnalyzerEditField.Value};
            %instrumentAddresses = {'GPIB1::5::INSTR', 'GPIB0::20::INSTR', 'TCPIP0::192.168.3.70::hislip0::INSTR'};
            lampHandles = {app.Lamp_Power_Supply, app.Lamp_Signal_Generator, app.Lamp_Spectrum_Analyzer};
            labelHandles = {app.Label_Power_Supply, app.Label_Signal_Generator, app.Label_Spectrum_Analyzer};
            
            allConnected = true;

            % Loops through all instruments to connect to them
            for i = 1:numel(instrumentNames)
                fieldName = instrumentNames{i};
                address = instrumentAddresses{i};
                lamp = lampHandles{i};
                label = labelHandles{i};

                % Checks if the instrument is already connected
                if ~isempty(app.(fieldName)) && isvalid(app.(fieldName))
                    continue
                end

                % If not, connect to the instrument
                app.(fieldName) = app.connectInstrument(address);
                if isempty(app.(fieldName))
                    lamp.Color = 'red';
                    label.Text = 'Unsuccessful Connection';
                    allConnected = false;
                else
                    lamp.Color = 'green';
                    label.Text = 'Successful Connection';
                end
            end

            % Enable Power supply settings panel if all instruments are connected
            if allConnected
                app.PowerSupplySettingsPanel.Enable = 'on';
            else
                app.PowerSupplySettingsPanel.Enable = 'off';
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % This function disconnects the instruments from the app per
            % the user's request
            instrumentNames = {'Power_Supply', 'Signal_Generator', 'Spectrum_Analyzer'};
            lampHandles = {app.Lamp_Power_Supply, app.Lamp_Signal_Generator, app.Lamp_Spectrum_Analyzer};
            labelHandles = {app.Label_Power_Supply, app.Label_Signal_Generator, app.Label_Spectrum_Analyzer};

            % Loop trough all instruments to check which ones to disconnect
            for i = 1:numel(instrumentNames)
                fieldName = instrumentNames{i};
                lamp = lampHandles{i};
                label = labelHandles{i};

                % Check if the instrument is connected
                if isempty(app.(fieldName)) || ~isvalid(app.(fieldName))
                    continue; 
                end

                % If connected, disconnect the instrument
                delete(app.(fieldName));
                app.(fieldName) = [];
                lamp.Color = 'red';
                label.Text = 'Disconnected';
            end

            % Disable power supply settings panel
            app.PowerSupplySettingsPanel.Enable = 'off';
        end

        % Button pushed function: ApplyVoltageButton
        function ApplyVoltageButtonPushed(app, event)
            % This function sets the power supply's voltage. It checks that
            % the user input is within the supply's range. 
            supplyVoltage = app.SupplyVoltage.Value;

            if (supplyVoltageVoltage.Value >= 0 && supplyVoltage <= 6)
                supplyMode = 'P6V';
            elseif (supplyVoltage > 6 && supplyVoltage <= 25)
                supplyMode = 'P25V';
            elseif (supplyVoltage >= -25 && supplyVoltage < 0)
                supplyMode = 'N25V';
            else
                app.Label_psu_settings.Text = 'Check Supply Ranges';
                app.Lamp_psu_settings.Color = 'red';
                return
            end     

            app.Label_psu_settings.Text = 'Applied';
            app.Lamp_psu_settings.Color = 'green';

            % Reset the power supply 
            writeline(app.Power_Supply, '*RST');

            % Set power supply in remote mode
            writeline(app.Power_Supply, ':SYSTem:REMote');

            % Set the voltage and current (default) of the power supply
            writeline(app.Power_Supply, sprintf(':APPLy %s,%g,%s', supplyMode, supplyVoltage, 'DEFault'));

            app.displaySettings('appliedVoltage');
        end

        % Button pushed function: StartAcquisitionSingle
        function StartAcquisitionSinglePushed(app, event)
            % This function runs the single frequency measurement type, 
            % the user enters a single frequency and a range of input 
            % power to test the device.
            app.input_PdBm_Single = app.StartPowerSingle.Value:app.PowerStepSingle.Value:app.EndPowerSingle.Value;
            app.input_Freq_Single = app.FrequencySingle.Value;
            app.output_PdBm = zeros(1, length(app.input_PdBm_Single));
            app.P_DC = zeros(1, length(app.input_PdBm_Single));

            % Turn on the power supply
            writeline(app.Power_Supply, sprintf(':OUTPut:STATe %d', 1));

            % Reset the spectrum analyzer
            writeline(app.Spectrum_Analyzer, '*RST'); 

            % Set the center frequency, the span, the number of sweep 
            % points, and the reference level of the spectrum analyzer
            writeline(app.Spectrum_Analyzer, sprintf(':SENSe:FREQuency:CENTer %g', app.CenterFrequencySingle.Value));
            writeline(app.Spectrum_Analyzer, sprintf(':SENSe:FREQuency:SPAN %g', app.SpanSingle.Value));
            writeline(app.Spectrum_Analyzer, sprintf(':SENSe:SWEep:POINts %d', app.SweepPointsSingle.Value));
            writeline(app.Spectrum_Analyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', app.ReferenceLevelSingle.Value));

            % Set the data format to be 64-bit real numbers and swapped
            % endianness
            writeline(app.Spectrum_Analyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
            writeline(app.Spectrum_Analyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));

            % Reset the signal generator
            writeline(app.Signal_Generator, '*RST');

            % Set the frequency of the input signal
            writeline(app.Signal_Generator, sprintf(':SOURce:FREQuency:CW %d', app.FrequencySingle.Value));
            
            % Turn on the signal generator 
            writeline(app.Signal_Generator, sprintf(':OUTPut:STATe %d', 1));

            for i = 1:length(app.input_PdBm_Single)
                [app.output_PdBm(i), app.P_DC(i)] = app.measure_RFoutput(app.Signal_Generator, app.Spectrum_Analyzer, app.Power_Supply, app.input_PdBm_Single(i));
            end

            % Turn off the power supply 
            writeline(app.Power_Supply, sprintf(':OUTPut:STATe %d', 0));

            % Turn off the signal generator 
            writeline(app.Signal_Generator, sprintf(':OUTPut:STATe %d', 0));
            writeline(app.Signal_Generator, sprintf(':OUTPut:MODulation:STATe %d', 0));
            writeline(app.Signal_Generator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %s', 'MIN'));

            % Calculate Gain, DE, and PAE
            [app.Gain, app.DE, app.PAE] = app.measure_RFparameters(app.input_PdBm_Single, app.output_PdBm, app.P_DC);

            app.displaySettings('appliedSingle');
        end

        % Button pushed function: StartAcquisitionSweep
        function StartAcquisitionSweepPushed(app, event)
            % This function runs the sweep frequency measurement type, the
            % user enters a range of frequencies and a range of input 
            % power to test the device.
            app.input_Freq_Sweep = app.StartFrequencySweep.Value:app.FrequencyStepSweep.Value:app.EndFrequencySweep.Value;
            app.input_PdBm_Sweep = app.StartPowerSweep.Value:app.PowerStepSweep.Value:app.EndPowerSweep.Value;
            app.output_PdBm = zeros(1, length(app.input_PdBm_Sweep));
            app.P_DC = zeros(1, length(app.input_PdBm_Sweep));

            %% TEST IF THIS WORKS NOW
            app.peak_1dB = zeros(length(app.input_Freq), 1);
            app.peak_3dB = zeros(length(app.input_Freq), 1);
            
             
            % Turn on the power supply
            writeline(app.Power_Supply, sprintf(':OUTPut:STATe %d', 1));

            % Reset the signal analyzer
            writeline(app.Spectrum_Analyzer, '*RST');

            % Set the number of sweep points, and the reference level of the spectrum analyzer
            writeline(app.Spectrum_Analyzer, sprintf(':SENSe:SWEep:POINts %d', app.SweepPointsSweep.Value));
            writeline(app.Spectrum_Analyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', app.ReferenceLevelSweep.Value));

            % Set the data format to be 64-bit real numbers and swapped
            % endianness
            writeline(app.Spectrum_Analyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
            writeline(app.Spectrum_Analyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));

            % Reset the signal generator
            writeline(app.Signal_Generator, '*RST');

            % Turn on the signal generator 
            writeline(app.Signal_Generator, sprintf(':OUTPut:STATe %d', 1));

            for i = 1:length(app.input_Freq_Sweep)
                center_Freq = app.input_Freq_Sweep(i);
                span = app.input_Freq_Sweep(i) / 2;

                % Set the input frequency of the signal generator
                writeline(app.Signal_Generator, sprintf(':SOURce:FREQuency:CW %d', app.input_Freq_Sweep(i)));

                % Set the center and span frequencies of the spectrum analyzer
                writeline(app.Spectrum_Analyzer, sprintf(':SENSe:FREQuency:CENTer %g', center_Freq));
                writeline(app.Spectrum_Analyzer, sprintf(':SENSe:FREQuency:SPAN %g', span));

                for j = 1:length(app.input_PdBm_Sweep)
                    [app.output_PdBm(i, j), app.P_DC(i, j)] = app.measure_RFoutput(app.Signal_Generator, app.Spectrum_Analyzer, app.Power_Supply, app.input_PdBm_Sweep(j));
                end
            end

            % Turn off the power supply
            writeline(app.Power_Supply, sprintf(':OUTPut:STATe %d', 0));

            % Turn off the signal generator 
            writeline(app.Signal_Generator, sprintf(':OUTPut:STATe %d', 0));
            writeline(app.Signal_Generator, sprintf(':OUTPut:MODulation:STATe %d', 0));
            writeline(app.Signal_Generator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %s', 'MIN'));
            
            % Calculate the Gain, the DE, and the PAE
            [app.Gain, app.DE, app.PAE] = app.measure_RFparameters(app.input_PdBm_Sweep, app.output_PdBm, app.P_DC);

            app.displaySettings('appliedSweep');
        end

        % Button pushed function: SavetoFileSingle
        function SavetoFileSinglePushed(app, event)
            app.saveToFile('single', app.FilenameSingle.Value);
        end

        % Button pushed function: SavetoFileSweep
        function SavetoFileSweepPushed(app, event)
            app.saveToFile('sweep', app.FilenameSweep.Value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Find the maximum gain, then the -1dB and -3dB compression
            % points for a single run.
            maxGain = max(app.Gain);
            idx_1dB = find(app.Gain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(app.Gain <= (maxGain - 3), 1, 'first');

            % Plot Gain on the left y-axis
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, app.output_PdBm, app.Gain, 'k-', 'DisplayName', 'Gain');
            hold(app.UIAxes, 'on');

            % Plot the -1dB and -3dB compression points
            plot(app.UIAxes, app.output_PdBm(idx_1dB), app.Gain(idx_1dB), 'ro', 'MarkerSize', 10, 'DisplayName', 'Gain_-1dB');
            plot(app.UIAxes, app.output_PdBm(idx_3dB), app.Gain(idx_3dB), 'bo', 'MarkerSize', 10, 'DisplayName', 'Gain_-3dB');
            ylabel(app.UIAxes, 'Gain (dB)');
            hold(app.UIAxes, 'off');

            % Plot DE and PAE on the right y-axis
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, app.output_PdBm, app.DE, 'b-', 'DisplayName', 'DE'); % Use a different color/style for DE
            plot(app.UIAxes, app.output_PdBm, app.PAE, 'r--', 'DisplayName', 'PAE'); % Use a different color/style for PAE
            ylabel(app.UIAxes, 'Efficiency/Power');

            xlabel(app.UIAxes, 'Output Power (dBm)');
            title(app.UIAxes, 'Gain, DE, and PAE vs. Output Power');
            legend(app.UIAxes, 'Location', 'best');
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function is executed upon closeup of the app and
            % decides wether to quit or stay on the app. If the user 
            % quits, close all active connections. If the user stays, do 
            % nothing.

            objects_to_delete = {app.Power_Supply, app.Signal_Generator, app.Spectrum_Analyzer};
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");

            switch choice
                case "OK"
                    for i = 1:numel(objects_to_delete)
                        if ~isempty(objects_to_delete{i})
                            delete(objects_to_delete{i});
                        end
                    end
                    delete(app)
                case "Cancel"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.InstrumentControlTab = uitab(app.TabGroup);
            app.InstrumentControlTab.Title = 'Instrument Control';
            app.InstrumentConnectionsPanel = uipanel(app.InstrumentControlTab);
            app.InstrumentConnectionsPanel.Title = 'Instrument Connections';
            app.ConnectButton = uibutton(app.InstrumentConnectionsPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp_Spectrum_Analyzer = uilamp(app.InstrumentConnectionsPanel);
            app.Lamp_Signal_Generator = uilamp(app.InstrumentConnectionsPanel);
            app.Lamp_Power_Supply = uilamp(app.InstrumentConnectionsPanel);
            app.Label_Spectrum_Analyzer = uilabel(app.InstrumentConnectionsPanel);
            app.Label_Spectrum_Analyzer.Text = '';
            app.Label_Signal_Generator = uilabel(app.InstrumentConnectionsPanel);
            app.Label_Signal_Generator.Text = '';
            app.Label_Power_Supply = uilabel(app.InstrumentConnectionsPanel);
            app.Label_Power_Supply.Text = '';
            app.PowerSupplyUnitEditFieldLabel = uilabel(app.InstrumentConnectionsPanel);
            app.PowerSupplyUnitEditFieldLabel.Text = 'Power Supply Unit';
            app.PowerSupplyUnitEditField = uieditfield(app.InstrumentConnectionsPanel, 'text');
            app.PowerSupplyUnitEditField.Tooltip = {''};
            app.PowerSupplyUnitEditField.Value = 'Device Address';
            app.SignalGeneratorEditFieldLabel = uilabel(app.InstrumentConnectionsPanel);
            app.SignalGeneratorEditFieldLabel.Text = 'Signal Generator';
            app.SignalGeneratorEditField = uieditfield(app.InstrumentConnectionsPanel, 'text');
            app.SignalGeneratorEditField.Value = 'Device Address';
            app.SpectrumAnalyzerEditFieldLabel = uilabel(app.InstrumentConnectionsPanel);
            app.SpectrumAnalyzerEditFieldLabel.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerEditField = uieditfield(app.InstrumentConnectionsPanel, 'text');
            app.SpectrumAnalyzerEditField.Value = 'Device Address';
            app.DisconnectButton = uibutton(app.InstrumentConnectionsPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PowerSupplySettingsPanel = uipanel(app.InstrumentControlTab);
            app.PowerSupplySettingsPanel.Title = 'Power Supply Settings';
            app.ApplyVoltageButton = uibutton(app.PowerSupplySettingsPanel, 'push');
            app.ApplyVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyVoltageButtonPushed, true);
            app.ApplyVoltageButton.Text = 'Apply';
            app.Lamp_psu_settings = uilamp(app.PowerSupplySettingsPanel);
            app.Label_psu_settings = uilabel(app.PowerSupplySettingsPanel);
            app.Label_psu_settings.Text = '';
            app.SupplyVoltageVEditFieldLabel = uilabel(app.PowerSupplySettingsPanel);
            app.SupplyVoltageVEditFieldLabel.Text = 'Supply Voltage (V)';
            app.SupplyVoltage = uieditfield(app.PowerSupplySettingsPanel, 'numeric');
            app.AppExplanationLabel = uilabel(app.InstrumentControlTab);
            app.AppExplanationLabel.Text = 'App Explanation ';
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.UIAxes = uiaxes(app.SingleTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.SingleTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SpectrumAnalyzerSettingsPanel_Single = uipanel(app.SingleTab);
            app.SpectrumAnalyzerSettingsPanel_Single.Title = 'Spectrum Analyzer Settings';
            app.SpanHzEditFieldLabel = uilabel(app.SpectrumAnalyzerSettingsPanel_Single);
            app.SpanHzEditFieldLabel.Text = 'Span (Hz)';
            app.SweepPointsEditFieldLabel = uilabel(app.SpectrumAnalyzerSettingsPanel_Single);
            app.SweepPointsEditFieldLabel.Text = 'Sweep Points';
            app.ReferenceLeveldBEditFieldLabel = uilabel(app.SpectrumAnalyzerSettingsPanel_Single);
            app.ReferenceLeveldBEditFieldLabel.Text = 'Reference Level (dB)';
            app.StartAcquisitionSingle = uibutton(app.SpectrumAnalyzerSettingsPanel_Single, 'push');
            app.StartAcquisitionSingle.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionSinglePushed, true);
            app.StartAcquisitionSingle.Text = 'Start Acquisition';
            app.ReferenceLevelSingle = uieditfield(app.SpectrumAnalyzerSettingsPanel_Single, 'numeric');
            app.ReferenceLevelSingle.Placeholder = 'dBm';
            app.SweepPointsSingle = uieditfield(app.SpectrumAnalyzerSettingsPanel_Single, 'numeric');
            app.SpanSingle = uieditfield(app.SpectrumAnalyzerSettingsPanel_Single, 'numeric');
            app.SpanSingle.Placeholder = 'Hz';
            app.CenterFrequencyHzEditFieldLabel = uilabel(app.SpectrumAnalyzerSettingsPanel_Single);
            app.CenterFrequencyHzEditFieldLabel.Text = 'Center Frequency (Hz)';
            app.CenterFrequencySingle = uieditfield(app.SpectrumAnalyzerSettingsPanel_Single, 'numeric');
            app.CenterFrequencySingle.Placeholder = 'Hz';
            app.SignalGeneratorSettingsPanel_Single = uipanel(app.SingleTab);
            app.SignalGeneratorSettingsPanel_Single.Title = 'Signal Generator Settings';
            app.StepSizeEditField_3Label = uilabel(app.SignalGeneratorSettingsPanel_Single);
            app.StepSizeEditField_3Label.Text = 'Step Size';
            app.EndPowerdBmEditField_2Label = uilabel(app.SignalGeneratorSettingsPanel_Single);
            app.EndPowerdBmEditField_2Label.Text = 'End Power (dBm)';
            app.PowerStepSingle = uieditfield(app.SignalGeneratorSettingsPanel_Single, 'numeric');
            app.EndPowerSingle = uieditfield(app.SignalGeneratorSettingsPanel_Single, 'numeric');
            app.EndPowerSingle.Placeholder = 'dBm';
            app.StartPowerdBmEditField_2Label = uilabel(app.SignalGeneratorSettingsPanel_Single);
            app.StartPowerdBmEditField_2Label.Text = 'Start Power (dBm)';
            app.StartPowerSingle = uieditfield(app.SignalGeneratorSettingsPanel_Single, 'numeric');
            app.StartPowerSingle.Placeholder = 'dBm';
            app.FrequencyHzEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Single);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencySingle = uieditfield(app.SignalGeneratorSettingsPanel_Single, 'numeric');
            app.FrequencySingle.Placeholder = 'Hz';
            app.SavetoFileSingle = uibutton(app.SingleTab, 'push');
            app.SavetoFileSingle.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileSinglePushed, true);
            app.SavetoFileSingle.Text = 'Save to File';
            app.FilenameEditField_2Label = uilabel(app.SingleTab);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.FilenameSingle = uieditfield(app.SingleTab, 'text');
            app.PlotButton = uibutton(app.SingleTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.SpectrumAnalyzerSettingsPanel_Sweep = uipanel(app.SweepTab);
            app.SpectrumAnalyzerSettingsPanel_Sweep.Title = 'Spectrum Analyzer Settings';
            app.SweepPointsEditField_2Label = uilabel(app.SpectrumAnalyzerSettingsPanel_Sweep);
            app.SweepPointsEditField_2Label.Text = 'Sweep Points';
            app.ReferenceLeveldBEditFieldLabel_2 = uilabel(app.SpectrumAnalyzerSettingsPanel_Sweep);
            app.ReferenceLeveldBEditFieldLabel_2.Text = 'Reference Level (dB)';
            app.StartAcquisitionSweep = uibutton(app.SpectrumAnalyzerSettingsPanel_Sweep, 'push');
            app.StartAcquisitionSweep.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionSweepPushed, true);
            app.StartAcquisitionSweep.Text = 'Start Acquisition';
            app.ReferenceLevelSweep = uieditfield(app.SpectrumAnalyzerSettingsPanel_Sweep, 'numeric');
            app.ReferenceLevelSweep.Placeholder = 'dBm';
            app.SweepPointsSweep = uieditfield(app.SpectrumAnalyzerSettingsPanel_Sweep, 'numeric');
            app.SignalGeneratorSettingsPanel_Sweep = uipanel(app.SweepTab);
            app.SignalGeneratorSettingsPanel_Sweep.Title = 'Signal Generator Settings';
            app.EndFrequencyHzEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.EndFrequencyHzEditFieldLabel.Text = 'End Frequency (Hz)';
            app.StepSizeEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StartPowerdBmEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.EndPowerdBmEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.StepSizeEditField_2Label = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.StepSizeEditField_2Label.Text = 'Step Size';
            app.PowerStepSweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.EndPowerSweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.StartPowerSweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.FrequencyStepSweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.EndFrequencySweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.StartFrequencyHzEditFieldLabel = uilabel(app.SignalGeneratorSettingsPanel_Sweep);
            app.StartFrequencyHzEditFieldLabel.Text = 'Start Frequency (Hz)';
            app.StartFrequencySweep = uieditfield(app.SignalGeneratorSettingsPanel_Sweep, 'numeric');
            app.SavetoFileSweep = uibutton(app.SweepTab, 'push');
            app.SavetoFileSweep.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileSweepPushed, true);
            app.SavetoFileSweep.Text = 'Save to File';
            app.FilenameEditFieldLabel = uilabel(app.SweepTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameSweep = uieditfield(app.SweepTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RF_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end