classdef RFappV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabs                        matlab.ui.container.TabGroup
        PowerAmplifierTab               matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        PowerSupplyTab                  matlab.ui.container.Tab
        SignalGeneratorTab              matlab.ui.container.Tab
        SpectrumAnalyzerTab             matlab.ui.container.Tab
        MeasurementPanel                matlab.ui.container.Panel
        PlotPAData                      matlab.ui.control.Button
        MeasurementDropDown             matlab.ui.control.DropDown
        MeasurementDropDownLabel        matlab.ui.control.Label
        SavePAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        SingleGain                      matlab.ui.control.UIAxes
        SingleInVersusOut               matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SweepPeakDEPAE                  matlab.ui.control.UIAxes
        SweepCompressionPoints          matlab.ui.control.UIAxes
        SweepPeakGain                   matlab.ui.control.UIAxes
        SweepGain                       matlab.ui.control.UIAxes
        testTab                         matlab.ui.container.Tab
        CenterFrequency                 matlab.ui.control.NumericEditField
        CenterFrequencyMHzEditField     matlab.ui.control.Label
        Span                            matlab.ui.control.NumericEditField
        SweepPoints                     matlab.ui.control.NumericEditField
        ReferenceLevel                  matlab.ui.control.NumericEditField
        SpanMHzEditField                matlab.ui.control.Label
        SweepPointsEditField            matlab.ui.control.Label
        ReferenceLeveldBEditField       matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SupplyVoltage                   matlab.ui.control.NumericEditField
        SupplyVoltageVEditField         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        PowerStep                       matlab.ui.control.NumericEditField
        EndPower                        matlab.ui.control.NumericEditField
        StartPower                      matlab.ui.control.NumericEditField
        FrequencyStep                   matlab.ui.control.NumericEditField
        EndFrequency                    matlab.ui.control.NumericEditField
        StartFrequency                  matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStepMHzLabel           matlab.ui.control.Label
        StartFrequencyMHzLabel          matlab.ui.control.Label
        EndFrequencyMHzLabel            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        InstrumentConnectionsPanel      matlab.ui.container.Panel
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SpectrumAnalyzerDropDownLabel   matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorDropDownLabel    matlab.ui.control.Label
        PowerSupplyDropDown             matlab.ui.control.DropDown
        PowerSupplyDropDownLabel        matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAScanButton                    matlab.ui.control.Button
        AntennaTab                      matlab.ui.container.Tab
        TestSettingsPanel               matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        blankButton                     matlab.ui.control.Button
        LoadTestButton                  matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        SaveTestButton                  matlab.ui.control.Button
        PlotSettingsPanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        PlottingAngleDropDown           matlab.ui.control.DropDown
        PlottingAngleDropDownLabel      matlab.ui.control.Label
        PlottingFrequencyDropDown       matlab.ui.control.DropDown
        PlottingFrequencyDropDownLabel  matlab.ui.control.Label
        Button2                         matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        InstrumentConnectionsPanel_2    matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EMCenterDropDown                matlab.ui.control.DropDown
        EMCenterDropDownLabel           matlab.ui.control.Label
        VNADropDown                     matlab.ui.control.DropDown
        VNADropDownLabel                matlab.ui.control.Label
        AntennaConnectButton            matlab.ui.control.Button
        AntennaScanButton               matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        VNATab                          matlab.ui.container.Tab
        AntennaPortMeasurement          matlab.ui.control.DropDown
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        VNAStartFrequency               matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        TableSpeed                      matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_14    matlab.ui.control.Label
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        TowerTab                        matlab.ui.container.Tab
        TowerSpeed                      matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_19    matlab.ui.control.Label
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        LinearSliderTab                 matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingmEditFieldLabel   matlab.ui.control.Label
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        CurrentPositioncmEditFieldLabel  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        DMeasurementTab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GainvsAngle                     matlab.ui.control.UIAxes
        GainvsFrequency                 matlab.ui.control.UIAxes
        ReturnLoss                      matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Instrument Objects
        PowerSupply       % Power Supply
        SignalGenerator   % Signal Generator
        SpectrumAnalyzer  % Spectrum Analyzer
        VNA               % Vector Network Analyzer
        EMCenter          % Anechoic Chamber Control  
        LinearSlider      % Linear Slider

        % Power Amplifier Properties
        mType             % Type of measurement for PA: single or sweep
        input_Freq_Single % Frequency for Single Measurement (Hz)
        input_PdBm_Single % RF Input Power for Single Measurement (dBm)
        input_Freq_Sweep  % Frequecy for Sweep Measurement (Hz)
        input_PdBm_Sweep  % RF Input Power for Sweep Measurement (dBm)
        output_PdBm       % RF Output Power (dBm)
        DCPower           % DC Power
        PAGain            % Power Amplifier - Gain (dB)
        DE                % Drain Efficiency (%)
        PAE               % Power-Added Efficiency (%)

        % Antenna Properties
        numPorts          % Number of ports for Antenna: 2-port or 3-port
        azimuthAngles     % Azimuth Angles for Table Measurement (deg)
        elevationAngles   % Elevation Angles for Tower Measurement (deg)
        testFreqs         % Frequency for Table Measurement (Hz)
        AntennaGain       % Antenna Gain (dBi)
        sData             % Raw S-Parameters from VNA
        sParameters       % Processed S-Parameters specific to the number of ports    
        selAngle          % Selected angle to plot versus frequency spectrum
        selFrequency      % Selected frequency to plot versus angle spectrum

        offsetSpacing     % Spacing between antennas (m)
        linearSpacing     % Spacing added by linear slider (m)
        setupSpacing      % Total spacing between antennas accounting for LS and offset (m)

        angleRange
        frequencyRange
        RadiationPlot2D
    end
   
    
    methods (Access = private)

        function getInstrumentResources(app, instrumentNames)
            % This function gets a list of connected VISA resources, and
            % populates them to the dropdown menus.
            resourceList = visadevlist("Timeout", 40);
            allResources = resourceList.ResourceName';
            
            % Clear the items of each instrument dropdown
            for i = 1:numel(instrumentNames)
                dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                if isprop(app, dropdownFieldName) 
                    app.(dropdownFieldName).Items = {};
                end
            end
            
            % Populate each instrument's dropdown with the resource addresses
            for i = 1:numel(instrumentNames)
                dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                if isprop(app, dropdownFieldName)
                    for j = 1:numel(allResources)
                        app.(dropdownFieldName).Items{end+1} = char(allResources(j));
                    end
                end
            end
        end
        
        function connectInstrument(app, instrumentNames, instrumentResources)
            % This function checks if the connection to the instrument is
            % valid, and connects the specified address of the instrument.   
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                
                % Check if the dropdown is enabled, indicating no existing connection
                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource)
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            if isvalid(app.(instrumentName))
                                app.(instrumentName).ByteOrder = 'little-endian';
                                app.(instrumentName).Timeout = 20;
                                writeline(app.(instrumentName), '*RST');
                                app.(dropdownFieldName).Enable = 'off';
                            else
                                error('Invalid VISA object');
                            end
                        catch ME
                            uialert(app.UIFigure, sprintf('Failed to connect to %s: %s.', instrumentResource, ME.message), 'Error', 'Icon', 'warning');
                        end
                    end
                else
                    continue
                end
            end
        end
    
        function runPAMeasurement(app, measurementType)
            % This function runs either the sweep frequency measurement or the single
            % frequency measurement based on the measurementType parameter.
            try
                supplyVoltage = app.SupplyVoltage.Value;
                if supplyVoltage > 25 || supplyVoltage < -25
                    error('Voltage level outside of power supply range');
                end
                writeline(app.PowerSupply, '*RST');
                writeline(app.PowerSupply, ':SYSTem:REMote');
                writeline(app.PowerSupply, sprintf(':APPLy %s,%g,%s', 'P25V', supplyVoltage, 'MAXimum'));
            catch errorObj
                errordlg(errorObj.message, 'Power Supply Error');
                return;
            end
        
            if strcmp(measurementType, 'Sweep')
                app.input_Freq_Sweep = app.StartFrequency.Value*1E6:app.FrequencyStep.Value*1E6:app.EndFrequency.Value*1E6;
                app.input_PdBm_Sweep = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.output_PdBm = zeros(1, length(app.input_PdBm_Sweep));
                app.DCPower = zeros(1, length(app.input_PdBm_Sweep));
            else
                app.input_Freq_Single = app.StartFrequency.Value * 1E6;
                app.input_PdBm_Single = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.output_PdBm = zeros(1, length(app.input_PdBm_Single));
                app.DCPower = zeros(1, length(app.input_PdBm_Single));
            end

            sweepPoints = app.SweepPointsSingle.Value;
            refLevel = app.ReferenceLevelSingle.Value;
        
            % Reset the signal analyzer
            writeline(app.SpectrumAnalyzer, '*RST');
            writeline(app.SpectrumAnalyzer, sprintf(':SENSe:SWEep:POINts %d', sweepPoints));
            writeline(app.SpectrumAnalyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', refLevel));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));
        
            % Reset the signal generator
            writeline(app.SignalGenerator, '*RST');
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 1));
        
            % Turn on the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 1));
        
            if strcmp(measurementType, 'Sweep')
                for i = 1:length(app.input_Freq_Sweep)
                    center_Freq = app.input_Freq_Sweep(i);
                    span = app.input_Freq_Sweep(i) / 2;
                    writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.input_Freq_Sweep(i)));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', center_Freq));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', span));
        
                    for j = 1:length(app.input_PdBm_Sweep)
                        [app.output_PdBm(i, j), app.DCPower(i, j)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.input_PdBm_Sweep(j));
                    end
                end
            else
                writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.input_Freq_Single));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', app.CenterFrequency.Value * 1E6));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', app.Span.Value * 1E6));
        
                for i = 1:length(app.input_PdBm_Single)
                    [app.output_PdBm(i), app.DCPower(i)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.input_PdBm_Single(i));
                end
            end
        
            % Turn off the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 0));
        
            % Turn off the signal generator
            writeline(app.SignalGenerator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %s', 'MIN'));
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 0));
            writeline(app.SignalGenerator, sprintf(':OUTPut:MODulation:STATe %d', 0));
        
            % Calculate Gain, DE, and PAE
            if strcmp(measurementType, 'Sweep')
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.input_PdBm_Sweep, app.output_PdBm, app.DCPower);
            else
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.input_PdBm_Single, app.output_PdBm, app.DCPower);
            end
        end

        function peakValues = measureRFpeaks(app)
            % This function is used with the sweep type measurement, it
            % calculates the peak values of several RF paramters (Gain, DE,
            % PAE, Saturation Power, -1dB and -3dB compression points).

            peak_Gain = zeros(size(app.input_Freq_Sweep, 1), 1);
            peak_1dB  = zeros(size(app.input_Freq_Sweep, 1), 1);
            peak_3dB  = zeros(size(app.input_Freq_Sweep, 1), 1);
        
            % Window size for the moving average
            windowSize = 3; 
            
            for i = 1:size(app.input_Freq_Sweep, 1)
                smoothedGain = movmean(app.PAGain(i, :), windowSize);
                peak_Gain(i) = max(smoothedGain);
            
                % Find the -1dB compression point
                idx_1dB = find(smoothedGain <= (peak_Gain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak_1dB(i) = app.output_PdBm(i, idx_1dB);
                else
                    peak_1dB(i) = NaN;
                end
            
                % Find the -3dB compression point
                idx_3dB = find(smoothedGain <= (peak_Gain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak_3dB(i) = app.output_PdBm(i, idx_3dB);
                else
                    peak_3dB(i) = NaN;
                end
            end
        
            saturation_PdBm = max(app.output_PdBm, [], 2);
            peak_DE  = max(app.DE, [], 2);
            peak_PAE = max(app.PAE, [], 2);
        
            peakValues = {saturation_PdBm, peak_Gain, peak_DE, peak_PAE, peak_1dB', peak_3dB'};
        end

        function plotPASingleMeasurement(app)
            % This function plots the data from the single measurement
            % type. The first plot shows input power vs. output power. The
            % second plot shows the DE, PAE, gain, and compression points
            % if found.
            cla(app.SingleInVersusOut);
            cla(app.SingleGain);

            % Window size for the moving average
            % REMOVE ONCE POWER SUPPLY IS FIXED
            windowSize = 3; 
            smoothedGain = movmean(app.PAGain, windowSize);
            maxGain = max(smoothedGain);
            idx_1dB = find(smoothedGain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(smoothedGain <= (maxGain - 3), 1, 'first'); 
            [saturation_power, saturation_idx] = max(app.output_PdBm);

            % Plot Input Power vs Output Power
            plot(app.SingleInVersusOut, app.input_PdBm_Single, app.output_PdBm, 'blue');
            xlabel(app.SingleInVersusOut, 'Input Power (dBm)');
            ylabel(app.SingleInVersusOut, 'Output Power (dBm)');
            title(app.SingleInVersusOut, 'Output Power vs. Input Power');

            % Plot Gain on the left y-axis
            yyaxis(app.SingleGain, 'left');
            h1 = plot(app.SingleGain, app.output_PdBm, app.PAGain, 'black');
            hold(app.SingleGain, 'on');

            % Plot the compression points
            if ~isempty(idx_1dB)
                h4 = plot(app.SingleGain, app.output_PdBm(idx_1dB), app.PAGain(idx_1dB), 'blueo', 'MarkerSize', 10);
            end
            if ~isempty(idx_3dB)
                h5 = plot(app.SingleGain, app.output_PdBm(idx_3dB), app.PAGain(idx_3dB), 'redo', 'MarkerSize', 10);
            end
            if ~isempty(saturation_idx)
                h6 = plot(app.SingleGain, saturation_power, app.PAGain(saturation_idx), 'greeno', 'MarkerSize', 10);
            end
            ylabel(app.SingleGain, 'Gain (dB)');

            % Plot DE and PAE on the right y-axis
            yyaxis(app.SingleGain, 'right');
            h2 = plot(app.SingleGain, app.output_PdBm, app.DE, 'blue-');
            h3 = plot(app.SingleGain, app.output_PdBm, app.PAE, 'red--');
            ylabel(app.SingleGain, 'Efficiency (%)');

            hold(app.SingleGain, 'off');
            xlabel(app.SingleGain, 'Output Power (dBm)');
            title(app.SingleGain, 'Gain, DE, and PAE vs. Output Power');

            % Legend order
            legendEntries = {'Gain', 'DE', 'PAE'};
            legendHandles = [h1, h2, h3];
            if exist('h4', 'var')
                legendHandles(end+1) = h4; 
                legendEntries{end+1} = 'Gain_{-1dB}'; 
            end
            if exist('h5', 'var')
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'Gain_{-3dB}'; 
            end
            if exist('h6', 'var')
                legendHandles(end+1) = h6; 
                legendEntries{end+1} = 'Saturation'; 
            end
            legend(app.SingleGain, legendHandles, legendEntries, 'Location', 'west');
            
            improveAxesAppearance(app.SingleInVersusOut, 'false');
            improveAxesAppearance(app.SingleGain, 'true');   
        end

        function plotPASweepMeasurement(app)
            % This function plots the data from the sweep measurement type.
            % The first plots shows gain vs. output power. Peak DE, PAE,
            % and gain are also plotted for each frequency. Finally the
            % saturation, and compression points are plotted. If no curve
            % appears for the compression points then there was a frequency
            % with no compression.
            cla(app.SweepGain);
            cla(app.SweepCompressionPoints);
            cla(app.SweepPeakGain);
            cla(app.SweepPeakDEPAE);

            % Get peak values
            peakValues = app.measureRFpeaks();          
            
            % Plot Gain versus output power over the frequency sweep
            hold(app.SweepGain, 'on'); 
            for i = 1:size(app.output_PdBm, 1)
                plot(app.SweepGain, app.output_PdBm(i, :), app.PAGain(i, :));
            end
            hold(app.SweepGain, 'off');
            title(app.SweepGain, 'Gain vs. Output Power');
            xlabel(app.SweepGain, 'Output Power (dBm)');
            ylabel(app.SweepGain, 'Gain (dB)');
            
            % % Plot peak Gain over the frequency sweep
            plot(app.SweepCompressionPoints, app.input_Freq_Sweep(1:size(app.output_PdBm, 1))/1E9, peakValues{2}(1:size(app.output_PdBm, 1)), 'blue');
            title(app.SweepCompressionPoints, 'Peak Gain');
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)');
            ylabel(app.SweepCompressionPoints, 'Gain (dB)');
            
            % Plot peak DE and peak PAE over the frequency sweep
            hold(app.SweepPeakGain, 'on');
            h1 = plot(app.SweepPeakGain, app.input_Freq_Sweep(1:size(app.output_PdBm, 1))/1E9, peakValues{3}(1:size(app.output_PdBm, 1)), 'blue');
            h2 = plot(app.SweepPeakGain, app.input_Freq_Sweep(1:size(app.output_PdBm, 1))/1E9, peakValues{4}(1:size(app.output_PdBm, 1)), 'red');
            hold(app.SweepPeakGain, 'off');
            title(app.SweepPeakGain, 'Peak DE and PAE');
            xlabel(app.SweepPeakGain, 'Frequency (GHz)');
            ylabel(app.SweepPeakGain, 'Efficiency (%)');
            legend(app.SweepPeakGain, [h1, h2], 'DE', 'PAE', 'Location', 'best');

            % Plot saturation power and compression points over the
            % frequency sweep, if a compression point was not found, 
            % nothing is plotted.
            plotHandles = {};
            plotColors = {'black', 'red', 'blue'};
            xData = {[], [], []};
            yData = {[], [], []};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            hold(app.SweepPeakDEPAE, 'on'); 

            %Loop through data and collect non-zero points
            for i = 1:size(app.output_PdBm, 1)
                if peakValues{1}(i) ~= 0
                    xData{1} = [xData{1}, app.input_Freq_Sweep(i)/1E9];
                    yData{1} = [yData{1}, peakValues{1}(i)];
                end
                if peakValues{5}(i) ~= 0
                    xData{2} = [xData{2}, app.input_Freq_Sweep(i)/1E9];
                    yData{2} = [yData{2}, peakValues{5}(i)];
                end
                if peakValues{6}(i) ~= 0
                    xData{3} = [xData{3}, app.input_Freq_Sweep(i)/1E9];
                    yData{3} = [yData{3}, peakValues{6}(i)];
                end   
            end
            
            for idx = 1:3
                if ~isempty(xData{idx})
                    plotHandles{end+1} = plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, [plotColors{idx}, '-']);
                    plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, plotColors{idx});
                end
            end
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)');
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)');
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points');

            if ~isempty(plotHandles)
                legend(app.SweepPeakDEPAE, [plotHandles{:}], legendLabels(1:length(plotHandles)), 'Location', 'best');
            end
            hold(app.SweepPeakDEPAE, 'off');

            improveAxesAppearance(app.SweepGain, 'false', 2.5);
            improveAxesAppearance(app.SweepCompressionPoints, 'false', 2.5);
            improveAxesAppearance(app.SweepPeakGain, 'false', 2.5);
            improveAxesAppearance(app.SweepPeakDEPAE, 'false', 2.5);
        end

        function label = formatFreq(~, Frequency)
            % This function takes in a frequency value and returns it in scientific
            % notation for plotting purposes.
            if Frequency >= 1e9
                label = sprintf('%.2f GHz', Frequency / 1e9);
            elseif Frequency >= 1e6
                label = sprintf('%.2f MHz', Frequency / 1e6);
            elseif Frequency >= 1e3
                label = sprintf('%.2f kHz', Frequency / 1e3);
            else
                label = sprintf('%.2f Hz', Frequency);
            end     
        end

        function run2DMeasurement(app, startFreq, endFreq)
            sweepPts = 601;  
            tableSpeed = app.TableSpeed.Value;
            tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
            arraySize = sweepPts * length(tableAngles);
        
            app.azimuthAngles = zeros(1, arraySize);
            app.testFreqs = zeros(1, arraySize);
            app.AntennaGain = zeros(1, arraySize);
      
            idx = 1;
            try
                writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));
                for azimuthAngle = tableAngles
                    writeline(app.EMCenter, sprintf('1A:SK %d', azimuthAngle));
                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));

                    while status ~= 1
                        status = writeread(app.EMCenter,"1A:*OPC?");
                        status = str2double(strtrim(status));
                    end

                    dataPts = idx:idx+sweepPts-1;
       
                    [app.sData, VNAFreqs] = measureSParameters(app.VNA, app.numPorts, startFreq, endFreq);
                    app.azimuthAngles(dataPts) = azimuthAngle;   % Azimuth Angle 
                    app.testFreqs(dataPts) = VNAFreqs;          % VNA Frequencies
                    app.sParameters{1}(dataPts) = app.sData{1}; % S11
                    app.sParameters{2}(dataPts) = app.sData{2}; % S21
                    app.AntennaGain = measureAntennaGain(app.testFreqs(dataPts), app.sParameters{2}(dataPts), app.setupSpacing);

                    idx = idx + sweepPts;
                end
            catch ME
                disp(ME.message);
                uialert(app.UIFigure, sprintf('Error while running 2D measurement.'), 'Error', 'Icon', 'warning');
            end
        end

        function run3DMeasurement(app)
            sweepPts = 601;  

            tableSpeed = app.TableSpeed.Value;
            tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;

            % towerSpeed = app.;
            % towerAngles = app.:app.:app.;
        
            %%array allocation
            arraySize = sweepPts * length(tableAngles) * length(towerAngles);
            app.AntennaGain = zeros(1, arraySize);
            app.azimuthAngles = zeros(1, arraySize);
            app.testFreqs = zeros(1, arraySize);

            idx = 1;

            try 
                %set speed of tower and table

                for elevationAngle = towerAngles
                    %move to elevation angle
                    for azimuthAngle = tableAngles
                        % move to azimuth angle
                        % get data same way as 2D measurement, but with phi
                        % axis?

                        dataPts = idx:idx+sweepPts-1;
       
                        [app.sData, VNAFreqs] = measureSParameters(app.VNA, app.numPorts, startFreq, endFreq);
                        app.elevationAngles(dataPts) = elevationAngle;     % Elevation Angle
                        app.azimuthAngles(dataPts) = azimuthAngle;         % Azimuth Angle 
                        app.testFreqs(dataPts) = VNAFreqs;                 % VNA Frequencies
                        app.sParameters{1}(dataPts) = app.sData{1};        % S11
                        app.sParameters{2}(dataPts) = app.sData{2};        % S21
                        app.AntennaGain = measureAntennaGain(app.testFreqs(dataPts), app.sParameters{2}(dataPts), app.setupSpacing);

                    end
                end
            catch ME
                disp(ME.message);
                uialert(app.UIFigure, sprintf('Error while running 3D measurement.'), 'Error', 'Icon', 'warning');
            end
        end

        function plotAntenna2DMeasurement(app)
            cla(app.GainvsFrequency);
            cla(app.ReturnLoss);
            cla(app.GainvsAngle);
            cla(app.RadiationPlot2D);

            % Specified Angle Plotting  
            angleIndices = (app.angleRange*601+1):((app.angleRange+1)*601);
            angle_Gain = app.AntennaGain(angleIndices);
            angle_S11 = app.sParameters{1}(angleIndices);
            filtered_S21 = app.sParameters{2}(angleIndices);

            % Specified Frequency Plotting
            frequencyIndices = app.frequencyRange:601:length(app.AntennaGain);
            frequency_Gain = app.AntennaGain(frequencyIndices);

            % Antenna Gain vs. Frequency, at specified angle
            plot(app.GainvsFrequency, app.TableFreqs/1E9, angle_Gain);
            title(app.GainvsFrequency, sprintf('Antenna Gain vs Frequency at %d%s', str2double(app.selAngle), '^\circ'));
            xlabel(app.GainvsFrequency, 'Frequency (GHz)');
            ylabel(app.GainvsFrequency, 'Gain (dBi)');

            % Return Loss (dB) Plot
            plot(app.ReturnLoss, app.TableFreqs/1E9, angle_S11);
            title(app.ReturnLoss, 'Return Loss')
            xlabel(app.ReturnLoss, 'Frequency (GHz)');
            ylabel(app.ReturnLoss, 'Return Loss (dB)');

            % Antenna Gains vs. Angles, at specified frequency.
            plot(app.GainvsAngle, app.TableAngles, frequency_Gain);
            title(app.GainvsAngle, sprintf('Antenna Gain vs Angle at %.2f GHz', str2double(app.selFrequency)));
            xlabel(app.GainvsAngle, 'Angle (degrees)');
            ylabel(app.GainvsAngle, 'Gain (dBi)');
            xlim(app.GainvsAngle, [0 360]);

            % 2D Radiation Pattern Plot
            polarplot(app.RadiationPlot2D, deg2rad(app.TableAngles), frequency_Gain);
            title(app.RadiationPlot2D, sprintf('2D Radiation Pattern at %.2f GHz', str2double(app.selFrequency)));
            rlim(app.RadiationPlot2D, [min(frequency_Gain)-1 max(frequency_Gain)+1]);
            thetalim(app.RadiationPlot2D, [0 360]);

            improveAxesAppearance(app.GainvsFrequency, 'false', 2);
            improveAxesAppearance(app.ReturnLoss, 'false', 2);
            improveAxesAppearance(app.GainvsAngle, 'false', 2);
            %improveAxesAppearance(app.RadiationPlot2D);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application settings
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            
            % Handle PA testing settings
            app.mType = 'Single';
            app.Span.Enable = 'on';
            app.CenterFrequency.Enable = 'on';
            app.EndFrequency.Enable = 'off';
            app.FrequencyStep.Enable = 'off';

            % Handle Antenna testing settings
            app.numPorts = 2;
            app.RadiationPlot2D = polaraxes(app.GridLayout3);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  

            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';
        end

        % Button pushed function: PAScanButton
        function PAScanResources(app, event)
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            app.getInstrumentResources(instrumentNames)
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            app.connectInstrument(instrumentNames, instrumentResources);    
        end

        % Value changed function: MeasurementDropDown
        function PAMeasurementType(app, event)
            type = app.MeasurementDropDown.Value;
            app.mType = string(type);
            if strcmp(app.mType, 'Sweep')
                app.Span.Enable = 'off';
                app.CenterFrequency.Enable = 'off';
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.mType, 'Single')
                app.Span.Enable = 'on';
                app.CenterFrequency.Enable = 'on';
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            app.runPAMeasurement(app.mType)
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            if strcmp(app.mType, 'Sweep')
                app.plotPASweepMeasurement();
            else
                app.plotPASingleMeasurement();
            end
            %app.PlotPAData.Enable = 'off';
        end

        % Button pushed function: SavePAData
        function PASaveData(app, event)
            if strcmp(app.mType, 'Sweep')
                numFreq = length(app.input_Freq_Sweep);
                maxLength = max(length(app.input_PdBm_Sweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.input_Freq_Sweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.input_PdBm_Sweep', app.output_PdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            else
                maxLength = max(length(app.input_PdBm_Single));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.input_Freq_Single)) = app.input_Freq_Single;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.input_PdBm_Single', app.output_PdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                disp(app.mType)
                combinedData = loadData('PA');
                if ~isempty(combinedData) 
                    if strcmp(app.mType, 'Sweep')
                        app.input_Freq_Sweep = combinedData.FrequencyHz';
                        app.input_PdBm_Sweep = combinedData.InputPowerdBm';
                        app.output_PdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    else
                        app.input_Freq_Single = unique(combinedData.FrequencyHz)';
                        app.input_PdBm_Single = combinedData.InputPowerdBm';
                        app.output_PdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: AntennaScanButton
        function AntennaScanResources(app, event)
            instrumentNames = {'VNA', 'EMCenter'};
            app.getInstrumentResources(instrumentNames)
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it. 
            instrumentNames = {'VNA', 'EMCenter'};
            instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
            app.connectInstrument(instrumentNames, instrumentResources);       
        end

        % Value changed function: AntennaPortMeasurement
        function AntennaPortSetup(app, event)
            if strcmp(app.AntennaPortMeasurement.Value, '2-Port')
                 app.numPorts = 2;
            elseif strcmp(app.AntennaPortMeasurement.Value, '3-Port')
                 app.numPorts = 3;
            end
        end

        % Button down function: LinearSliderTab
        function LinearSliderConnect(app, event)
            try 
                app.LinearSlider = tcpclient('192.168.0.100', 1206);
                app.LinearSlider.ByteOrder = 'little-endian';
                app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
            catch ME
                uialert(app.UIFigure, 'Failed to connect to linear slider.', 'Error', 'Icon', 'warning');
            end

            app.AntennaCurrentSpacing.Enable = 'on';
            app.LinearSliderCurrentPosition.Enable = 'on';
            app.SpeedPresetDropDown.Enable = 'on';
            app.MovetoPositionButton.Enable = 'on';
            app.LinearSliderNewPosition.Enable = 'on';
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderSetSpeed(app, event)
             if isempty(app.LinearSlider)
                 try 
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                catch ME
                    uialert(app.UIFigure, 'Failed to connect to linear slider.', 'Error', 'Icon', 'warning');
                 end
            end

            speed_preset = str2double(app.SpeedPresetDropDown.Value);

            try 
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speed_preset));
            catch ME
                uialert(app.UIFigure, 'Failed to set linear slider speed.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderMovePosition(app, event)
            if isempty(app.LinearSlider)
                 try 
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                catch ME
                    uialert(app.UIFigure, 'Failed to connect to linear slider.', 'Error', 'Icon', 'warning');
                 end
            end
            
            try
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;
                end
            catch ME
                uialert(app.UIFigure, 'Failed to move linear slider to new position.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            startFreq = app.VNAStartFreq.Value;
            endFreq = app.VNAEndFreq.Value;
            app.run2DMeasurement(startFreq, endFreq);
        end

        % Button pushed function: PlotButton
        function AntennaPlotMeasurement(app, event)
            if (app.numPorts == 2)
                app.plotAntenna2DMeasurement();
            else
                app.plotAntenna3DMeasurement();
            end
        end

        % Button pushed function: SaveTestButton
        function AntennaSaveData(app, event)
            if app.numPorts == 2
                combinedData = [double(app.azimuthAngles)', double(app.testFreqs)', double(app.AntennaGain)', double(app.sParameters{1})', double(app.sParameters{2})'];
                parameterNames = {'Table Angle (°)', 'Frequency (Hz)', 'Gain (dBi)', 'S11 (dB)', 'S21 (dB)'};
            elseif app.numPorts == 3
                % combinedData = 
                % parameterNames = 
            end

            combinedNames = cell(1, size(combinedData, 2));
            for i = 1:length(parameterNames)
                    combinedNames{i} = parameterNames{i};
            end
            
            try
                saveData(combinedData, combinedNames);
            catch ME
                uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end     
        end

        % Button pushed function: LoadTestButton
        function AntennaLoadData(app, event)
            try
                combinedData = loadData('Antenna');
                if ~isempty(combinedData) 
                    if app.numPorts == 2
                        app.azimuthAngles = unique(combinedData.TableAngle)';
                        app.testFreqs = unique(combinedData.FrequencyHz)';
                        app.AntennaGain = combinedData.GaindBi';
                        app.sParameters = {combinedData.S11', combinedData.S21'};
                    elseif app.numPorts == 3  
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
            
            app.PlottingAngleDropDown.Items = string(app.azimuthAngles);
            app.selAngle = app.PlottingAngleDropDown.Items(1);
            app.angleRange = 0;
            app.PlottingFrequencyDropDown.Items = string(app.testFreqs/1E9);
            app.selFrequency = app.PlottingFrequencyDropDown.Items(1);
            app.frequencyRange = 1;
        end

        % Value changed function: PlottingAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            app.selAngle = app.PlottingAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlottingAngleDropDown.Items, app.selAngle)) - 1;
        end

        % Value changed function: PlottingFrequencyDropDown
        function AntennaSelectedFrequencyValue(app, event)
            app.selFrequency = app.PlottingFrequencyDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlottingFrequencyDropDown.Items, app.selFrequency));
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function is executed upon closeup of the app and
            % decides wether to quit or stay on the app. If the user 
            % quits, close all active connections. If the user stays, do 
            % nothing.
            objects_to_delete = {app.PowerSupply, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");

            switch choice
                case "OK"
                    for i = 1:numel(objects_to_delete)
                        if ~isempty(objects_to_delete{i})
                            delete(objects_to_delete{i});
                        end
                    end
                    delete(app)
                case "Cancel"
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.MainTabs = uitabgroup(app.UIFigure);
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.InstrumentConnectionsPanel = uipanel(app.PowerAmplifierTab);
            app.InstrumentConnectionsPanel.Title = 'Instrument Connections';
            app.PAScanButton = uibutton(app.InstrumentConnectionsPanel, 'push');
            app.PAScanButton.ButtonPushedFcn = createCallbackFcn(app, @PAScanResources, true);
            app.PAScanButton.Text = 'Scan';
            app.PAConnectButton = uibutton(app.InstrumentConnectionsPanel, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.PowerSupplyDropDownLabel = uilabel(app.InstrumentConnectionsPanel);
            app.PowerSupplyDropDownLabel.Text = 'Power Supply';
            app.PowerSupplyDropDown = uidropdown(app.InstrumentConnectionsPanel);
            app.PowerSupplyDropDown.Items = {'Address'};
            app.PowerSupplyDropDown.Value = 'Address';
            app.SignalGeneratorDropDownLabel = uilabel(app.InstrumentConnectionsPanel);
            app.SignalGeneratorDropDownLabel.Text = 'Signal Generator';
            app.SignalGeneratorDropDown = uidropdown(app.InstrumentConnectionsPanel);
            app.SignalGeneratorDropDown.Items = {'Address'};
            app.SignalGeneratorDropDown.Value = 'Address';
            app.SpectrumAnalyzerDropDownLabel = uilabel(app.InstrumentConnectionsPanel);
            app.SpectrumAnalyzerDropDownLabel.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerDropDown = uidropdown(app.InstrumentConnectionsPanel);
            app.SpectrumAnalyzerDropDown.Items = {'Address'};
            app.SpectrumAnalyzerDropDown.Value = 'Address';
            app.TabGroup = uitabgroup(app.PowerAmplifierTab);
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.SingleInVersusOut = uiaxes(app.SingleTab);
            title(app.SingleInVersusOut, 'Output Power vs. Input Power')
            xlabel(app.SingleInVersusOut, 'Input Power (dBm)')
            ylabel(app.SingleInVersusOut, 'Output Power (dBm)')
            zlabel(app.SingleInVersusOut, 'Z')
            app.SingleGain = uiaxes(app.SingleTab);
            title(app.SingleGain, 'Gain, DE, PAE vs. Output Power')
            xlabel(app.SingleGain, 'Output Power (dBm)')
            zlabel(app.SingleGain, 'Z')
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout2 = uigridlayout(app.SweepTab);
            app.SweepGain = uiaxes(app.GridLayout2);
            title(app.SweepGain, 'Gain vs. Output Power')
            xlabel(app.SweepGain, 'Output Power (dBm)')
            ylabel(app.SweepGain, 'Gain (dB)')
            zlabel(app.SweepGain, 'Z')
            app.SweepPeakGain = uiaxes(app.GridLayout2);
            title(app.SweepPeakGain, 'Peak DE and PAE')
            xlabel(app.SweepPeakGain, 'Frequency (GHz)')
            ylabel(app.SweepPeakGain, 'Efficiency (%)')
            zlabel(app.SweepPeakGain, 'Z')
            app.SweepCompressionPoints = uiaxes(app.GridLayout2);
            title(app.SweepCompressionPoints, 'Peak Gain')
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)')
            ylabel(app.SweepCompressionPoints, 'Gain (dB)')
            zlabel(app.SweepCompressionPoints, 'Z')
            app.SweepPeakDEPAE = uiaxes(app.GridLayout2);
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points')
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)')
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)')
            zlabel(app.SweepPeakDEPAE, 'Z')
            app.testTab = uitab(app.TabGroup);
            app.testTab.Title = 'test';
            app.Panel = uipanel(app.testTab);
            app.Panel_2 = uipanel(app.testTab);
            app.EndFrequencyMHzLabel = uilabel(app.Panel_2);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.StartFrequencyMHzLabel = uilabel(app.Panel_2);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.FrequencyStepMHzLabel = uilabel(app.Panel_2);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.StartPowerdBmEditFieldLabel = uilabel(app.Panel_2);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.EndPowerdBmEditFieldLabel = uilabel(app.Panel_2);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.PowerStepdBmEditField = uilabel(app.Panel_2);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.StartFrequency = uieditfield(app.Panel_2, 'numeric');
            app.EndFrequency = uieditfield(app.Panel_2, 'numeric');
            app.FrequencyStep = uieditfield(app.Panel_2, 'numeric');
            app.StartPower = uieditfield(app.Panel_2, 'numeric');
            app.EndPower = uieditfield(app.Panel_2, 'numeric');
            app.PowerStep = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.testTab);
            app.SupplyVoltageVEditField = uilabel(app.Panel_3);
            app.SupplyVoltageVEditField.Text = 'DC Voltage (V)';
            app.SupplyVoltage = uieditfield(app.Panel_3, 'numeric');
            app.ReferenceLeveldBEditField = uilabel(app.testTab);
            app.ReferenceLeveldBEditField.Text = 'Reference Level (dB)';
            app.SweepPointsEditField = uilabel(app.testTab);
            app.SweepPointsEditField.Text = 'Sweep Points';
            app.SpanMHzEditField = uilabel(app.testTab);
            app.SpanMHzEditField.Text = 'Span (MHz)';
            app.ReferenceLevel = uieditfield(app.testTab, 'numeric');
            app.SweepPoints = uieditfield(app.testTab, 'numeric');
            app.Span = uieditfield(app.testTab, 'numeric');
            app.CenterFrequencyMHzEditField = uilabel(app.testTab);
            app.CenterFrequencyMHzEditField.Text = 'Center Frequency (MHz)';
            app.CenterFrequency = uieditfield(app.testTab, 'numeric');
            app.MeasurementPanel = uipanel(app.PowerAmplifierTab);
            app.MeasurementPanel.Title = 'Measurement';
            app.StartPAMeasurement = uibutton(app.MeasurementPanel, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.MeasurementPanel, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.SavePAData = uibutton(app.MeasurementPanel, 'push');
            app.SavePAData.ButtonPushedFcn = createCallbackFcn(app, @PASaveData, true);
            app.SavePAData.Text = 'Save Test';
            app.MeasurementDropDownLabel = uilabel(app.MeasurementPanel);
            app.MeasurementDropDownLabel.Text = 'Measurement';
            app.MeasurementDropDown = uidropdown(app.MeasurementPanel);
            app.MeasurementDropDown.Items = {'Single', 'Sweep'};
            app.MeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @PAMeasurementType, true);
            app.MeasurementDropDown.Value = 'Single';
            app.PlotPAData = uibutton(app.MeasurementPanel, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.TabGroup5 = uitabgroup(app.PowerAmplifierTab);
            app.PowerSupplyTab = uitab(app.TabGroup5);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.SignalGeneratorTab = uitab(app.TabGroup5);
            app.SignalGeneratorTab.Title = 'Signal Generator';
            app.SpectrumAnalyzerTab = uitab(app.TabGroup5);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.TabGroup3 = uitabgroup(app.AntennaTab);
            app.DMeasurementTab = uitab(app.TabGroup3);
            app.DMeasurementTab.Title = '2D Measurement';
            app.GridLayout3 = uigridlayout(app.DMeasurementTab);
            app.ReturnLoss = uiaxes(app.GridLayout3);
            title(app.ReturnLoss, 'Return Loss')
            xlabel(app.ReturnLoss, 'Frequency (GHz)')
            ylabel(app.ReturnLoss, 'RL (dB)')
            zlabel(app.ReturnLoss, 'Z')
            app.GainvsFrequency = uiaxes(app.GridLayout3);
            title(app.GainvsFrequency, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency, 'Gain (dBi)')
            zlabel(app.GainvsFrequency, 'Z')
            app.GainvsAngle = uiaxes(app.GridLayout3);
            title(app.GainvsAngle, 'Antenna Gain vs. Angle')
            xlabel(app.GainvsAngle, 'Angle (°)')
            ylabel(app.GainvsAngle, 'Gain (dBi)')
            zlabel(app.GainvsAngle, 'Z')
            app.Tab2 = uitab(app.TabGroup3);
            app.Tab2.Title = 'Tab2';
            app.TabGroup4 = uitabgroup(app.AntennaTab);
            app.VNATab = uitab(app.TabGroup4);
            app.VNATab.Title = 'VNA';
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.VNATab);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency (MHz)';
            app.VNAStartFrequency = uieditfield(app.VNATab, 'numeric');
            app.EndFrequencyMHzLabel_6 = uilabel(app.VNATab);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency (MHz)';
            app.VNAEndFrequency = uieditfield(app.VNATab, 'numeric');
            app.MeasurementDropDown_2Label = uilabel(app.VNATab);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.AntennaPortMeasurement = uidropdown(app.VNATab);
            app.AntennaPortMeasurement.Items = {'2-Port', '3-Port'};
            app.AntennaPortMeasurement.ValueChangedFcn = createCallbackFcn(app, @AntennaPortSetup, true);
            app.AntennaPortMeasurement.Value = '2-Port';
            app.Table = uitab(app.TabGroup4);
            app.Table.Title = 'Table';
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.Table);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle (°)';
            app.TableStartAngle = uieditfield(app.Table, 'numeric');
            app.TableStartAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.Table);
            app.StartFrequectSpanMHzLabel_12.Text = 'Step Size (°)';
            app.TableStepAngle = uieditfield(app.Table, 'numeric');
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.Table);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle (°)';
            app.TableEndAngle = uieditfield(app.Table, 'numeric');
            app.TableEndAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_14 = uilabel(app.Table);
            app.StartFrequectSpanMHzLabel_14.Text = 'Table Speed';
            app.TableSpeed = uieditfield(app.Table, 'numeric');
            app.TableSpeed.Limits = [0 100];
            app.TowerTab = uitab(app.TabGroup4);
            app.TowerTab.Title = 'Tower';
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.TowerTab);
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle (°)';
            app.TowerStartAngle = uieditfield(app.TowerTab, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.TowerTab);
            app.StartFrequectSpanMHzLabel_17.Text = 'Step Size (°)';
            app.TowerStepAngle = uieditfield(app.TowerTab, 'numeric');
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.TowerTab);
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle (°)';
            app.TowerEndAngle = uieditfield(app.TowerTab, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_19 = uilabel(app.TowerTab);
            app.StartFrequectSpanMHzLabel_19.Text = 'Tower Speed';
            app.TowerSpeed = uieditfield(app.TowerTab, 'numeric');
            app.TowerSpeed.Limits = [0 100];
            app.LinearSliderTab = uitab(app.TabGroup4);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.LinearSliderTab.ButtonDownFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.CurrentPositioncmEditFieldLabel = uilabel(app.LinearSliderTab);
            app.CurrentPositioncmEditFieldLabel.Text = 'Current Position (cm)';
            app.LinearSliderCurrentPosition = uieditfield(app.LinearSliderTab, 'numeric');
            app.LinearSliderCurrentPosition.Limits = [0 200];
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.CurrentSpacingmEditFieldLabel = uilabel(app.LinearSliderTab);
            app.CurrentSpacingmEditFieldLabel.Text = 'Current Spacing (m)';
            app.AntennaCurrentSpacing = uieditfield(app.LinearSliderTab, 'numeric');
            app.AntennaCurrentSpacing.Limits = [0 Inf];
            app.AntennaCurrentSpacing.Editable = 'off';
            app.SpeedPresetDropDownLabel = uilabel(app.LinearSliderTab);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.LinearSliderTab);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderSetSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.MovetoPositionButton = uibutton(app.LinearSliderTab, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderMovePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.LinearSliderTab, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.StopButton = uibutton(app.LinearSliderTab, 'push');
            app.StopButton.Text = 'Stop';
            app.InstrumentConnectionsPanel_2 = uipanel(app.AntennaTab);
            app.InstrumentConnectionsPanel_2.Title = 'Instrument Connections';
            app.GridLayout6 = uigridlayout(app.InstrumentConnectionsPanel_2);
            app.AntennaScanButton = uibutton(app.GridLayout6, 'push');
            app.AntennaScanButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaScanResources, true);
            app.AntennaScanButton.Text = 'Scan';
            app.AntennaConnectButton = uibutton(app.GridLayout6, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.VNADropDownLabel = uilabel(app.GridLayout6);
            app.VNADropDownLabel.Text = 'VNA';
            app.VNADropDown = uidropdown(app.GridLayout6);
            app.VNADropDown.Items = {'Address'};
            app.VNADropDown.Value = 'Address';
            app.EMCenterDropDownLabel = uilabel(app.GridLayout6);
            app.EMCenterDropDownLabel.Text = 'EM Center';
            app.EMCenterDropDown = uidropdown(app.GridLayout6);
            app.EMCenterDropDown.Items = {'Address'};
            app.EMCenterDropDown.Value = 'Address';
            app.PlotSettingsPanel = uipanel(app.AntennaTab);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.GridLayout4 = uigridlayout(app.PlotSettingsPanel);
            app.PlotButton = uibutton(app.GridLayout4, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotButton.Text = 'Plot';
            app.Button2 = uibutton(app.GridLayout4, 'push');
            app.Button2.Text = 'Button2';
            app.PlottingFrequencyDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingFrequencyDropDownLabel.Text = 'Plotting Frequency';
            app.PlottingFrequencyDropDown = uidropdown(app.GridLayout4);
            app.PlottingFrequencyDropDown.Items = {'(GHz)'};
            app.PlottingFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlottingFrequencyDropDown.Value = '(GHz)';
            app.PlottingAngleDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingAngleDropDownLabel.Text = 'Plotting Angle';
            app.PlottingAngleDropDown = uidropdown(app.GridLayout4);
            app.PlottingAngleDropDown.Items = {'(°)'};
            app.PlottingAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlottingAngleDropDown.Value = '(°)';
            app.TestSettingsPanel = uipanel(app.AntennaTab);
            app.TestSettingsPanel.Title = 'Test Settings';
            app.GridLayout5 = uigridlayout(app.TestSettingsPanel);
            app.SaveTestButton = uibutton(app.GridLayout5, 'push');
            app.SaveTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaSaveData, true);
            app.SaveTestButton.Text = 'Save Test';
            app.StartTestButton = uibutton(app.GridLayout5, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.LoadTestButton = uibutton(app.GridLayout5, 'push');
            app.LoadTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadTestButton.Text = 'Load Test';
            app.blankButton = uibutton(app.GridLayout5, 'push');
            app.blankButton.Text = 'blank';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFappV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end